classdef TeamProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SoundPanel                    matlab.ui.container.Panel
        EditField_9                   matlab.ui.control.EditField
        EditField_8                   matlab.ui.control.EditField
        EditField_7                   matlab.ui.control.EditField
        EditField_6                   matlab.ui.control.EditField
        EditField_5                   matlab.ui.control.EditField
        EditField_4                   matlab.ui.control.EditField
        EditField_3                   matlab.ui.control.EditField
        EditField_2                   matlab.ui.control.EditField
        EditField                     matlab.ui.control.EditField
        Sound9Button                  matlab.ui.control.Button
        Sound8Button                  matlab.ui.control.Button
        Sound7Button                  matlab.ui.control.Button
        Sound6Button                  matlab.ui.control.Button
        Sound5Button                  matlab.ui.control.Button
        Sound4Button                  matlab.ui.control.Button
        Sound3Button                  matlab.ui.control.Button
        Sound2Button                  matlab.ui.control.Button
        Sound1Button                  matlab.ui.control.Button
        MessageTextArea               matlab.ui.control.TextArea
        MessageTextAreaLabel          matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        BasicModeTab                  matlab.ui.container.Tab
        SynthesizerPanel              matlab.ui.container.Panel
        LaButton                      matlab.ui.control.StateButton
        SolButton                     matlab.ui.control.StateButton
        FaButton                      matlab.ui.control.StateButton
        MiButton                      matlab.ui.control.StateButton
        ReButton                      matlab.ui.control.StateButton
        DoButton                      matlab.ui.control.StateButton
        SawWaveCheckBox               matlab.ui.control.CheckBox
        PulseWaveCheckBox             matlab.ui.control.CheckBox
        SineWaveCheckBox              matlab.ui.control.CheckBox
        PlayButton_2                  matlab.ui.control.StateButton
        SavingPlaceDropDownLabel      matlab.ui.control.Label
        SavingPlaceDropDown           matlab.ui.control.DropDown
        SaveButton_2                  matlab.ui.control.Button
        ChoppingPanel                 matlab.ui.container.Panel
        SavingPlaceDropDown_2Label    matlab.ui.control.Label
        SavingPlaceDropDown_2         matlab.ui.control.DropDown
        PlayButton                    matlab.ui.control.StateButton
        SaveButton                    matlab.ui.control.Button
        SoundNoDropDown_2             matlab.ui.control.DropDown
        SoundNoDropDown_2Label        matlab.ui.control.Label
        SoundSlider                   matlab.ui.control.RangeSlider
        SoundSliderLabel              matlab.ui.control.Label
        EqualizerPanel                matlab.ui.container.Panel
        SoundNoDropDown_5             matlab.ui.control.DropDown
        SoundNoDropDown_5Label        matlab.ui.control.Label
        PlayCheckBox_2                matlab.ui.control.CheckBox
        HzSlider_7                    matlab.ui.control.Slider
        HzLabel_3                     matlab.ui.control.Label
        KHzSlider_2                   matlab.ui.control.Slider
        KHzSlider_2Label              matlab.ui.control.Label
        KHzSlider                     matlab.ui.control.Slider
        KHzSliderLabel                matlab.ui.control.Label
        HzSlider_4                    matlab.ui.control.Slider
        HzLabel_4                     matlab.ui.control.Label
        HzSlider_3                    matlab.ui.control.Slider
        HzLabel_2                     matlab.ui.control.Label
        HzSlider_2                    matlab.ui.control.Slider
        HzLabel                       matlab.ui.control.Label
        EffectandSampleModificationPanel  matlab.ui.container.Panel
        FeedbackKnob                  matlab.ui.control.Knob
        FeedbackKnobLabel             matlab.ui.control.Label
        DelayKnob                     matlab.ui.control.Knob
        DelayKnobLabel                matlab.ui.control.Label
        PlaybackSpeedSlider           matlab.ui.control.Slider
        PlaybackSpeedSliderLabel      matlab.ui.control.Label
        PlaybackDirectionSwitch       matlab.ui.control.Switch
        PlaybackDirectionSwitchLabel  matlab.ui.control.Label
        PlaySwitch                    matlab.ui.control.ToggleSwitch
        PlaySwitchLabel               matlab.ui.control.Label
        VolumeKnob                    matlab.ui.control.Knob
        VolumeKnobLabel               matlab.ui.control.Label
        SoundNoDropDown               matlab.ui.control.DropDown
        SoundNoDropDownLabel          matlab.ui.control.Label
        LaunchPadTab                  matlab.ui.container.Tab
        Sound8Button_2                matlab.ui.control.StateButton
        Sound9Button_2                matlab.ui.control.StateButton
        Sound7Button_2                matlab.ui.control.StateButton
        Sound6Button_2                matlab.ui.control.StateButton
        Sound5Button_2                matlab.ui.control.StateButton
        Sound4Button_2                matlab.ui.control.StateButton
        Sound3Button_2                matlab.ui.control.StateButton
        Sound2Button_2                matlab.ui.control.StateButton
        Sound1Button_2                matlab.ui.control.StateButton
        SavingPlaceDropDown_3Label    matlab.ui.control.Label
        SavingPlaceDropDown_3         matlab.ui.control.DropDown
        SaveButton_3                  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % audio data sturcture 
        audio = struct('yValue', {}, 'frequency', {}, 'filename', {});
        % to check the auido is or not in the sound Button 
        sbutton = struct('is', {false, false, false, false, false, false, false, false, false});

        % Effect  & Sample Modification 
        player 
        playNum = 1; % default sound is sound 1

        % Chopping 
        chopNum = 1; % default sound number is sond 1
        yValChop
        chopSegment
        fsChop
        startIndexChop
        endIndexChop
        savingPlaceChop

        %Equalizer
        eqNum
        gain1 = 0; %60Hz
        gain2 = 0; %170Hz
        gain3 = 0; %310Hz
        gain4 = 0; %600Hz
        gain5 = 0;%1KHz
        gain6 = 0;%3KHz
        yValEq
        fsEq

        % Synthesizer 
        hertz = 440.00; % default value is 440Hz (A sound)  
        synthesizeAudio = 0; 
        sampleRate
        synPlayer 
        syntime
        synSaveNum 
        currentWave 
        synthesizeAudioFile

        % Launch Pad
        lunchPadSaveNum 
        lunchPad = struct('yValue', {}, 'player', {}); 
        

    end
    
    methods (Access = private)
        
        function [yvalue, Fs , fileName] = soundInput(app)
            [filename,filepath] =uigetfile({'*.wav; *.mp3'}, 'Select File to Open');
            fullname = [filepath, filename];
            [firstValue, secondValue] = audioread(fullname);
            yvalue = firstValue;
            Fs = secondValue; 
            fileName = filename;
        end
       
        
        function updateWaveform(app, src)
            if isplaying(src)
                currentPosition = get(src, 'CurrentSample');  
                startPosition = max(1, currentPosition - round(0.05 * app.sampleRate)); 
                audioSegment = app.synthesizeAudio(startPosition:currentPosition); 
                timeAxe2 = (0 : length(audioSegment)-1) / app.sampleRate;

                plot(app.UIAxes, timeAxe2, audioSegment); 
                
            end
        end
        
        function synthesizerFrequencyChanged(app)
            currentAudio = app.synthesizeAudio; 

            switch app.currentWave
                case 100 % sine wave
                    newAudio = sin(2 * pi * app.hertz * app.syntime)';
                    app.synthesizeAudio = [currentAudio; newAudio];
                    app.synthesizeAudioFile = 'sine_wave.wav'; 
                    audiowrite(app.synthesizeAudioFile, app.synthesizeAudio, app.sampleRate);
                case 101 % pulse wave
                    newAudio = square(2 * pi * app.hertz * app.syntime)';
                    app.synthesizeAudio = [currentAudio; newAudio];
                    app.synthesizeAudioFile = 'pulse_wave.wav'; 
                    audiowrite(app.synthesizeAudioFile, app.synthesizeAudio, app.sampleRate);
                case 110 % sawtooth wave 
                    newAudio = sawtooth(2 * pi * app.hertz * app.syntime)';
                    app.synthesizeAudio = [currentAudio; newAudio];
                    app.synthesizeAudioFile = 'sawtooth_wave.wav'; 
                    audiowrite(app.synthesizeAudioFile, app.synthesizeAudio, app.sampleRate);
            end
            
            app.synPlayer = audioplayer(app.synthesizeAudio, app.sampleRate);
            app.synPlayer.TimerFcn = @(src, event) app.updateWaveform(src);
            
        end
        
        function savingButton(app, number)
            switch number 
                case 1
                    app.EditField.Value = app.audio(number).filename;
                    app.Sound1Button.FontColor = [0, 0.8, 0]; 
                case 2
                    app.EditField_2.Value = app.audio(number).filename;
                    app.Sound2Button.FontColor = [0, 0.8, 0];
                case 3
                    app.EditField_3.Value = app.audio(number).filename;
                    app.Sound3Button.FontColor = [0, 0.8, 0];
                case 4
                    app.EditField_4.Value = app.audio(number).filename;
                    app.Sound4Button.FontColor = [0, 0.8, 0]; 
                case 5
                    app.EditField_5.Value = app.audio(number).filename;
                    app.Sound5Button.FontColor = [0, 0.8, 0]; 
                case 6
                    app.EditField_6.Value = app.audio(number).filename;
                    app.Sound6Button.FontColor = [0, 0.8, 0]; 
                case 7
                    app.EditField_7.Value = app.audio(number).filename;
                    app.Sound7Button.FontColor = [0, 0.8, 0];
                case 8
                    app.EditField_8.Value = app.audio(number).filename;
                    app.Sound8Button.FontColor = [0, 0.8, 0]; 
                case 9
                    app.EditField_9.Value = app.audio(number).filename;
                    app.Sound9Button.FontColor = [0, 0.8, 0];
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Sound1Button
        function Sound1ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput(); 
            app.sbutton(1).is = true;
            app.audio(1).yValue = y; 
            app.audio(1).frequency = Fs;
            app.audio(1).filename = filename;
            app.EditField.Value = filename;
            app.Sound1Button.FontColor = [0, 0.8, 0];
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
        end

        % Button pushed function: Sound2Button
        function Sound2ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput();
            app.sbutton(2).is = true;
            app.audio(2).yValue = y; 
            app.audio(2).frequency = Fs; 
            app.audio(2).filename = filename;
            app.EditField_2.Value = filename;
            app.Sound2Button.FontColor = [0, 0.8, 0];
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
        end

        % Button pushed function: Sound3Button
        function Sound3ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput(); 
            app.sbutton(3).is = true;
            app.audio(3).yValue = y; 
            app.audio(3).frequency = Fs; 
            app.audio(3).filename = filename;
            app.EditField_3.Value = filename;
            app.Sound3Button.FontColor = [0, 0.8, 0];
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
        end

        % Button pushed function: Sound4Button
        function Sound4ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput();
            app.sbutton(4).is = true;
            app.audio(4).yValue = y; 
            app.audio(4).frequency = Fs; 
            app.audio(4).filename = filename;
            app.EditField_4.Value = filename;
            app.Sound4Button.FontColor = [0, 0.8, 0];
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
        end

        % Button pushed function: Sound5Button
        function Sound5ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput(); 
            app.sbutton(5).is = true;
            app.audio(5).yValue = y; 
            app.audio(5).frequency = Fs; 
            app.audio(5).filename = filename;
            app.EditField_5.Value = filename;
            app.Sound5Button.FontColor = [0, 0.8, 0];
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
        end

        % Button pushed function: Sound6Button
        function Sound6ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput();
            app.sbutton(6).is = true;
            app.audio(6).yValue = y; 
            app.audio(6).frequency = Fs; 
            app.audio(6).filename = filename;
            app.EditField_6.Value = filename;
            app.Sound6Button.FontColor = [0, 0.8, 0];
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
        end

        % Button pushed function: Sound7Button
        function Sound7ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput(); 
            app.sbutton(7).is = true;
            app.audio(7).yValue = y; 
            app.audio(7).frequency = Fs; 
            app.audio(7).filename = filename;
            app.EditField_7.Value = filename;
            app.Sound7Button.FontColor = [0, 0.8, 0];
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
        end

        % Button pushed function: Sound8Button
        function Sound8ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput(); 
            app.sbutton(8).is = true;
            app.audio(8).yValue = y; 
            app.audio(8).frequency = Fs; 
            app.audio(8).filename = filename;
            app.EditField_8.Value = filename;
            app.Sound8Button.FontColor = [0, 0.8, 0];
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
        end

        % Button pushed function: Sound9Button
        function Sound9ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput(); 
            app.sbutton(9).is = true;
            app.audio(9).yValue = y; 
            app.audio(9).frequency = Fs; 
            app.audio(9).filename = filename;
            app.EditField_9.Value = filename;
            app.Sound9Button.FontColor = [0, 0.8, 0];
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
        end

        % Value changed function: SineWaveCheckBox
        function SineWaveCheckBoxValueChanged(app, event)
            % Reset other checkbox to avoid duplicate checks  
            app.PulseWaveCheckBox.Value = 0; 
            app.SawWaveCheckBox.Value = 0; 

            % for synthesizerFrequencyChanged function, saving current wave
            app.currentWave = 100; 
                
            % making sinewave 
            duration = 1; % playing time (10 second) 
            app.sampleRate = 44100; % sampling speed (Hz) 
            app.syntime = linspace(0, duration, duration * app.sampleRate);  % generating time vector
            app.synthesizeAudio = sin(2 * pi * app.hertz * app.syntime)'; % Generating sine wave with app.hertz
            app.synPlayer = audioplayer(app.synthesizeAudio, app.sampleRate); 
            app.synPlayer.TimerFcn = @(src, event) app.updateWaveform(src);

            % Saving audio 
            app.synthesizeAudioFile = 'sine_wave.wav'; 
            audiowrite(app.synthesizeAudioFile, app.synthesizeAudio, app.sampleRate);
        end

        % Value changed function: PulseWaveCheckBox
        function PulseWaveCheckBoxValueChanged(app, event)
            % Reset other checkbox to avoid duplicate checks  
            app.SineWaveCheckBox.Value = 0; 
            app.SawWaveCheckBox.Value = 0; 

            % for synthesizerFrequencyChanged function, saving current wave
            app.currentWave = 101; 
            
            % making sinewave 
            duration = 1; % playing time (10 second) 
            app.sampleRate = 44100; % sampling speed (Hz) 
            app.syntime = linspace(0, duration, duration * app.sampleRate); % generating time vector
            app.synthesizeAudio = square(2 * pi * app.hertz * app.syntime, 50)'; % Generating sine wave with app.hertz
            app.synPlayer = audioplayer(app.synthesizeAudio, app.sampleRate); 
            app.synPlayer.TimerFcn = @(src, event) app.updateWaveform(src);
            

            % Saving audio 
            app.synthesizeAudioFile = 'pulse_wave.wav'; 
            audiowrite(app.synthesizeAudioFile, app.synthesizeAudio, app.sampleRate);
        end

        % Value changed function: SawWaveCheckBox
        function SawWaveCheckBoxValueChanged(app, event)
            % Reset other checkbox to avoid duplicate checks 
            app.SineWaveCheckBox.Value = 0; 
            app.PulseWaveCheckBox.Value = 0; 

            % for synthesizerFrequencyChanged function, saving current wave
            app.currentWave = 110;

            % making sinewave 
            duration = 1; % playing time (10 second) 
            app.sampleRate = 44100; % sampling speed (Hz) 
            app.syntime = linspace(0, duration, duration * app.sampleRate);  % generating time vector
            app.synthesizeAudio = sawtooth(2 * pi * app.hertz * app.syntime)'; % Generating sine wave with app.hertz
            app.synPlayer = audioplayer(app.synthesizeAudio, app.sampleRate); % making audioplayer to play audio
            app.synPlayer.TimerFcn = @(src, event) app.updateWaveform(src); % function to show the audio wave 
            

            % Saving audio 
            app.synthesizeAudioFile = 'sawtooth_wave.wav'; 
            audiowrite(app.synthesizeAudioFile, app.synthesizeAudio, app.sampleRate);
        end

        % Value changed function: PlayButton_2
        function PlayButton_2ValueChanged(app, event)
            value = app.PlayButton_2.Value;
             
            if value == 1 % play the synthesized audio
               play(app.synPlayer); 
               app.PlayButton_2.Value = 0; %reset play button 
            else % stop the synthesized audio
               stop(app.synPlayer); 
            end
            
        end

        % Value changed function: SavingPlaceDropDown
        function SavingPlaceDropDownValueChanged(app, event)
            % for Synthesizer 
            value = app.SavingPlaceDropDown.Value;

            % selecting sound Button num to save the synthesized audio
            switch value 
                case "Sound 1"
                    app.synSaveNum = 1; 
                case "Sound 2"
                    app.synSaveNum = 2; 
                case "Sound 3"
                    app.synSaveNum = 3; 
                case "Sound 4"
                    app.synSaveNum = 4; 
                case "Sound 5"
                    app.synSaveNum = 5; 
                case "Sound 6"
                    app.synSaveNum = 6; 
                case "Sound 7"
                    app.synSaveNum = 7; 
                case "Sound 8"
                    app.synSaveNum = 8; 
                case "Sound 9"
                    app.synSaveNum = 9;
            end

        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            % for Synthesizer 
             
            if isscalar(app.synthesizeAudio)  % error detector(if there is no synthesized audio)  
                app.MessageTextArea.Value = "Need to make synthesized audio";
            else
                % saving y value and frequency in sound button  
                app.sbutton(app.synSaveNum).is =  true;
                app.audio(app.synSaveNum).yValue = app.synthesizeAudio; 
                app.audio(app.synSaveNum).frequency = app.sampleRate;
                app.audio(app.synSaveNum).filename = app.synthesizeAudioFile;
                
                % changing sound button color 
                savingButton(app, app.synSaveNum);
            end

            
        end

        % Value changed function: DoButton
        function DoButtonValueChanged(app, event)
            % reset other value
            app.ReButton.Value = 0; 
            app.MiButton.Value = 0; 
            app.FaButton.Value = 0; 
            app.SolButton.Value = 0;
            app.LaButton.Value = 0;

            app.hertz = 261.63; % C sound 
            
            % Change the audio's frequency 
            app.synthesizerFrequencyChanged(); 

            
        end

        % Value changed function: ReButton
        function ReButtonValueChanged(app, event)
            % reset other value
            app.DoButton.Value = 0; 
            app.MiButton.Value = 0; 
            app.FaButton.Value = 0; 
            app.SolButton.Value = 0;
            app.LaButton.Value = 0;
            
            app.hertz = 293.66; % D sound 

            % Change the audio's frequency 
            app.synthesizerFrequencyChanged(); 
            
        end

        % Value changed function: MiButton
        function MiButtonValueChanged(app, event)
            % reset other value
            app.DoButton.Value = 0; 
            app.ReButton.Value = 0; 
            app.FaButton.Value = 0; 
            app.SolButton.Value = 0;
            app.LaButton.Value = 0;
            
            app.hertz =  329.63; % E sound 

            % Change the audio's frequency 
            app.synthesizerFrequencyChanged(); 
            
        end

        % Value changed function: FaButton
        function FaButtonValueChanged(app, event)
            % reset other value
            app.DoButton.Value = 0; 
            app.ReButton.Value = 0; 
            app.MiButton.Value = 0; 
            app.SolButton.Value = 0;
            app.LaButton.Value = 0;
            
            app.hertz =  349.23; % F sound 

            % Change the audio's frequency 
            app.synthesizerFrequencyChanged(); 
            
        end

        % Value changed function: SolButton
        function SolButtonValueChanged(app, event)
            % reset other value
            app.DoButton.Value = 0; 
            app.ReButton.Value = 0; 
            app.MiButton.Value = 0; 
            app.FaButton.Value = 0;
            app.LaButton.Value = 0;
            
            app.hertz =  392.00; % G sound 

            % Change the audio's frequency 
            app.synthesizerFrequencyChanged(); 
            
        end

        % Value changed function: LaButton
        function LaButtonValueChanged(app, event)
            % reset other value
            app.DoButton.Value = 0; 
            app.ReButton.Value = 0; 
            app.MiButton.Value = 0; 
            app.FaButton.Value = 0;
            app.SolButton.Value = 0;
            
            app.hertz =  440.00; % A sound (Default sound)  
    
            % Change the audio's frequency 
            app.synthesizerFrequencyChanged(); 
            
        end

        % Value changed function: PlaySwitch
        function PlaySwitchValueChanged(app, event)
            value = app.PlaySwitch.Value;
            volume = app.VolumeKnob.Value;
            playSpeed = app.PlaybackSpeedSlider.Value;
            feedback = app.FeedbackKnob.Value;
            delay = app.DelayKnob.Value;


            % Play the audio
            if app.sbutton(app.playNum).is ==  true %Check the audio is saved or not 
                playYValue = app.audio(app.playNum).yValue;
                playFrequency = app.audio(app.playNum).frequency;
                %Create delay and feedback
                delayedY = zeros(size(playYValue));
                delayedSample = round(delay * playFrequency);
                delayedY(delayedSample + 1:end) = playYValue(1:end-delayedSample);
                outputY = delayedY + feedback*playYValue;

                if app.PlaybackDirectionSwitch.Value == "Backward" % backward play
                    backwardY = outputY(end:-1:1, :);
                    app.player = audioplayer(backwardY*volume, playFrequency*playSpeed);
                    play(app.player);
                else % forward play 
                    app.player = audioplayer(outputY*volume, playFrequency*playSpeed);
                    play(app.player);
                end
            else % error Detecting 
                app.MessageTextArea.Value = "The sound is empty. Please load the audio in the sound button"; 

            end

            if value == "Off"  %% pause the audio
                pause(app.player);
            end

            
        end

        % Value changed function: SoundNoDropDown
        function SoundNoDropDownValueChanged(app, event)
            % for Effect and Sample Modification 
            value = app.SoundNoDropDown.Value; 

            switch value
                case "Sound 1"
                    app.playNum = 1;
                case "Sound 2"
                    app.playNum = 2;
                case "Sound 3"
                    app.playNum = 3;
                case "Sound 4"
                    app.playNum = 4;
                case "Sound 5"
                    app.playNum = 5;
                case "Sound 6"
                    app.playNum = 6;
                case "Sound 7"
                    app.playNum = 7;
                case "Sound 8" 
                    app.playNum = 8;
                case "Sound 9 "
                    app.playNum = 9;
            end

            y = app.audio(app.playNum).yValue;
            Fs = app.audio(app.playNum).frequency; 
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
        end

        % Value changed function: SoundNoDropDown_5
        function SoundNoDropDown_5ValueChanged(app, event)
            % for Equalizer 
            value = app.SoundNoDropDown_5.Value;
            
            switch value
                case "Sound 1"
                    app.eqNum = 1;
                case "Sound 2"
                    app.eqNum = 2;
                case "Sound 3"
                    app.eqNum = 3;
                case "Sound 4"
                    app.eqNum = 4;
                case "Sound 5"
                    app.eqNum = 5;
                case "Sound 6"
                    app.eqNum = 6;
                case "Sound 7"
                    app.eqNum = 7;
                case "Sound 8" 
                    app.eqNum = 8;
                case "Sound 9 "
                    app.eqNum = 9;
            end
            
        end

        % Value changed function: SoundNoDropDown_2
        function SoundNoDropDown_2ValueChanged(app, event)
            % for Chopping 
            value = app.SoundNoDropDown_2.Value;

            switch value
                case "Sound 1"
                    app.chopNum = 1;
                case "Sound 2"
                    app.chopNum = 2;
                case "Sound 3"
                    app.chopNum = 3;
                case "Sound 4"
                    app.chopNum = 4;
                case "Sound 5"
                    app.chopNum = 5;
                case "Sound 6"
                    app.chopNum = 6;
                case "Sound 7"
                    app.chopNum = 7;
                case "Sound 8" 
                    app.chopNum = 8;
                case "Sound 9 "
                    app.chopNum = 9;
            end

        end

        % Value changed function: SavingPlaceDropDown_2
        function SavingPlaceDropDown_2ValueChanged(app, event)
            % for Chopping 
            value = app.SavingPlaceDropDown_2.Value;

            switch value
                case "Sound 1"
                    app.savingPlaceChop = 1;
                case "Sound 2"
                    app.savingPlaceChop = 2;
                case "Sound 3"
                    app.savingPlaceChop = 3;
                case "Sound 4"
                    app.savingPlaceChop = 4;
                case "Sound 5"
                    app.savingPlaceChop = 5;
                case "Sound 6"
                    app.savingPlaceChop = 6;
                case "Sound 7"
                    app.savingPlaceChop = 7;
                case "Sound 8" 
                    app.savingPlaceChop = 8;
                case "Sound 9 "
                    app.savingPlaceChop = 9;
            end
            
        end

        % Value changed function: SoundSlider
        function SoundSliderValueChanged(app, event)
            value = app.SoundSlider.Value;

            app.yValChop = app.audio(app.chopNum).yValue;
            app.fsChop = app.audio(app.chopNum).frequency; 

            app.startIndexChop = length(app.yValChop)*(value(1)/100);
            app.endIndexChop = length(app.yValChop)*(value(2)/100);
            disp(app.startIndexChop);
            disp(app.endIndexChop);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Write the segment to a new file
             
            app.chopSegment = app.yValChop(app.startIndexChop:app.endIndexChop, :);
    
            app.sbutton(app.savingPlaceChop).is =  true;
            app.audio(app.savingPlaceChop).yValue = app.chopSegment;
            app.audio(app.savingPlaceChop).frequency = app.fsChop;
            app.audio(app.savingPlaceChop).filename = "Chopped Audio";

           savingButton(app, app.savingPlaceChop);
            
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if (value == true)
                sound(app.chopSegment, app.fsChop, 16);
            else
                clear sound;
            end
        end

        % Value changed function: HzSlider_7
        function HzSlider_7ValueChanged(app, event)
            % 60Hz Equalizer 
            app.gain1 = app.HzSlider_7.Value;
        end

        % Value changed function: HzSlider_2
        function HzSlider_2ValueChanged(app, event)
            % 170Hz Equalizer 
            app.gain2 = app.HzSlider_2.Value;
        end

        % Value changed function: HzSlider_3
        function HzSlider_3ValueChanged(app, event)
            % 310Hz Equalizer
            app.gain3 = app.HzSlider_3.Value;
        end

        % Value changed function: HzSlider_4
        function HzSlider_4ValueChanged(app, event)
            %600Hz Equalizer
            app.gain4 = app.HzSlider_4.Value;
        end

        % Value changed function: KHzSlider
        function KHzSliderValueChanged(app, event)
            %1KHz Equalizer
            app.gain5 = app.KHzSlider.Value;
        end

        % Value changed function: KHzSlider_2
        function KHzSlider_2ValueChanged(app, event)
            %3KHz Equalizer 
            app.gain6 = app.KHzSlider_2.Value;
        end

        % Value changed function: PlayCheckBox_2
        function PlayCheckBox_2ValueChanged(app, event)
            value = app.PlayCheckBox_2.Value;
            app.yValEq = app.audio(app.eqNum).yValue;
            app.fsEq = app.audio(app.eqNum).frequency;

            equalizedFs = app.fsEq .* ...
                10^(app.gain1 / 60) .* ...
                10^(app.gain2 / 170) .* ...
                10^(app.gain3 / 310) .* ...
                10^(app.gain4 / 600) .* ...
                10^(app.gain5 / 1000) .* ...
                10^(app.gain6 / 3000);

            if (value == true)
                sound(app.yValEq, equalizedFs, 16);
            else
                clear sound;
            end
        end

        % Value changed function: Sound1Button_2
        function Sound1Button_2ValueChanged(app, event)
            value = app.Sound1Button_2.Value;
            [y, Fs] = audioread("sound\Sound1.wav"); 
            app.lunchPad(1).yValue = y(1:477991, 1); 
            app.lunchPad(1).player = audioplayer(y, Fs);
            if (value == true)
                play(app.lunchPad(1).player); 
            else
                stop(app.lunchPad(1).player);
            end
        end

        % Value changed function: Sound2Button_2
        function Sound2Button_2ValueChanged(app, event)
            value = app.Sound2Button_2.Value;
            [y, Fs] = audioread("sound\Sound2.wav"); 
            app.lunchPad(2).yValue = y(1:477991, 1); 
            app.lunchPad(2).player = audioplayer(y, Fs);
            if (value == true)
                play(app.lunchPad(2).player); 
            else
                stop(app.lunchPad(2).player);
            end
        end

        % Value changed function: Sound3Button_2
        function Sound3Button_2ValueChanged(app, event)
            value = app.Sound3Button_2.Value;
            [y, Fs] = audioread("sound\Sound3.wav"); 
            app.lunchPad(3).yValue = y(1:477991, 1); 
            app.lunchPad(3).player = audioplayer(y, Fs);
            if (value == true)
                play(app.lunchPad(3).player); 
            else
                stop(app.lunchPad(3).player);
            end
        end

        % Value changed function: Sound4Button_2
        function Sound4Button_2ValueChanged(app, event)
            value = app.Sound4Button_2.Value;
            [y, Fs] = audioread("sound\Sound4.wav"); 
            app.lunchPad(4).yValue = y(1:477991, 1); 
            app.lunchPad(4).player = audioplayer(y, Fs);
            if (value == true)
                play(app.lunchPad(4).player); 
            else
                stop(app.lunchPad(4).player);
            end
        end

        % Value changed function: Sound5Button_2
        function Sound5Button_2ValueChanged(app, event)
            value = app.Sound5Button_2.Value;
            [y, Fs] = audioread("sound\Sound5.wav"); 
            app.lunchPad(5).yValue = y(1:477991, 1); 
            app.lunchPad(5).player = audioplayer(y, Fs);
            if (value == true)
                play(app.lunchPad(5).player ); 
            else
                stop(app.lunchPad(5).player );
            end
        end

        % Value changed function: Sound6Button_2
        function Sound6Button_2ValueChanged(app, event)
            value = app.Sound6Button_2.Value;
            [y, Fs] = audioread("sound\Sound6.wav"); 
            app.lunchPad(6).yValue = y(1:477991, 1); 
            app.lunchPad(6).player = audioplayer(y, Fs);
            if (value == true)
                play(app.lunchPad(6).player); 
            else
                stop(app.lunchPad(6).player);
            end
        end

        % Value changed function: Sound7Button_2
        function Sound7Button_2ValueChanged(app, event)
            value = app.Sound7Button_2.Value;
            [y, Fs] = audioread("sound\Sound7.wav"); 
            app.lunchPad(7).yValue = y(1:477991, 1); 
            app.lunchPad(7).player = audioplayer(y, Fs);
            if (value == true)
                play(app.lunchPad(7).player); 
            else
                stop(app.lunchPad(7).player);
            end
        end

        % Value changed function: Sound8Button_2
        function Sound8Button_2ValueChanged(app, event)
            value = app.Sound8Button_2.Value;
            [y, Fs] = audioread("sound\Sound8.wav"); 
            app.lunchPad(8).yValue = y(1:477991, 1); 
            app.lunchPad(8).player = audioplayer(y, Fs);
            if (value == true)
                play(app.lunchPad(8).player); 
            else
                stop(app.lunchPad(8).player);
            end
        end

        % Value changed function: Sound9Button_2
        function Sound9Button_2ValueChanged(app, event)
            value = app.Sound9Button_2.Value;
            [y, Fs] = audioread("sound\Sound9.wav"); 
            app.lunchPad(9).yValue = y(1:477991, 1); 
            app.lunchPad(9).player = audioplayer(y, Fs);
            if (value == true)
                play(app.lunchPad(9).player); 
            else
                stop(app.lunchPad(9).player);
            end
        end

        % Value changed function: SavingPlaceDropDown_3
        function SavingPlaceDropDown_3ValueChanged(app, event)
            value = app.SavingPlaceDropDown_3.Value; 

            %selecting sound Button num to save the synthesized audio
            switch value 
                case "Sound 1"
                    app.lunchPadSaveNum = 1; 
                case "Sound 2"
                    app.lunchPadSaveNum = 2; 
                case "Sound 3"
                    app.lunchPadSaveNum = 3; 
                case "Sound 4"
                    app.lunchPadSaveNum = 4; 
                case "Sound 5"
                    app.lunchPadSaveNum = 5; 
                case "Sound 6"
                    app.lunchPadSaveNum = 6; 
                case "Sound 7"
                    app.lunchPadSaveNum = 7; 
                case "Sound 8"
                    app.lunchPadSaveNum = 8; 
                case "Sound 9"
                    app.lunchPadSaveNum = 9;
            end
 
        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
            saveYArray = zeros(477991, 1);
            if app.Sound1Button_2.Value == true
                saveYArray = saveYArray + app.lunchPad(1).yValue * 0.5;
            end
            if app.Sound2Button_2.Value == true
                saveYArray = saveYArray + app.lunchPad(2).yValue * 0.5; 
            end
            if app.Sound3Button_2.Value == true
                saveYArray = saveYArray + app.lunchPad(3).yValue * 0.5; 
            end
            if app.Sound4Button_2.Value == true
                saveYArray = saveYArray + app.lunchPad(4).yValue * 0.5; 
            end
            if app.Sound5Button_2.Value == true
                saveYArray = saveYArray + app.lunchPad(5).yValue * 0.5; 
            end
            if app.Sound6Button_2.Value == true
                saveYArray = saveYArray + app.lunchPad(6).yValue * 0.5;  
            end
            if app.Sound7Button_2.Value == true
                saveYArray = saveYArray + app.lunchPad(7).yValue * 0.5; 
            end
            if app.Sound8Button_2.Value == true
                saveYArray = saveYArray + app.lunchPad(8).yValue * 0.5;
            end
            if app.Sound9Button_2.Value == true
                saveYArray = saveYArray + app.lunchPad(9).yValue * 0.5; 
            end

            
            % saving y value and frequency in sound button  
            app.sbutton(app.lunchPadSaveNum).is =  true;
            app.audio(app.lunchPadSaveNum).yValue = saveYArray; 
            app.audio(app.lunchPadSaveNum).frequency = 44100;
            app.audio(app.lunchPadSaveNum).filename = "Lunch Pad";
            
            % changing sound button color 
            savingButton(app, app.lunchPadSaveNum);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sound Wave')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1.0'; '1.2'; '1.4'; '1.6'; '1.8'; '2'};
            app.UIAxes.YTickLabel = {'0'; '0.4'; '0.8'; '1.2'; '1.6'; '2'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicModeTab = uitab(app.TabGroup);
            app.BasicModeTab.Title = 'Basic Mode';
            app.EffectandSampleModificationPanel = uipanel(app.BasicModeTab);
            app.EffectandSampleModificationPanel.Title = 'Effect and Sample Modification ';
            app.SoundNoDropDownLabel = uilabel(app.EffectandSampleModificationPanel);
            app.SoundNoDropDownLabel.Text = 'Sound No.';
            app.SoundNoDropDown = uidropdown(app.EffectandSampleModificationPanel);
            app.SoundNoDropDown.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SoundNoDropDown.ValueChangedFcn = createCallbackFcn(app, @SoundNoDropDownValueChanged, true);
            app.SoundNoDropDown.Value = 'Sound 1';
            app.VolumeKnobLabel = uilabel(app.EffectandSampleModificationPanel);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.EffectandSampleModificationPanel, 'continuous');
            app.VolumeKnob.Limits = [0 10];
            app.VolumeKnob.Value = 5;
            app.PlaySwitchLabel = uilabel(app.EffectandSampleModificationPanel);
            app.PlaySwitchLabel.Text = 'Play';
            app.PlaySwitch = uiswitch(app.EffectandSampleModificationPanel, 'toggle');
            app.PlaySwitch.ValueChangedFcn = createCallbackFcn(app, @PlaySwitchValueChanged, true);
            app.PlaybackDirectionSwitchLabel = uilabel(app.EffectandSampleModificationPanel);
            app.PlaybackDirectionSwitchLabel.Text = 'Playback Direction';
            app.PlaybackDirectionSwitch = uiswitch(app.EffectandSampleModificationPanel, 'slider');
            app.PlaybackDirectionSwitch.Items = {'Forward', 'Backward'};
            app.PlaybackDirectionSwitch.Value = 'Forward';
            app.PlaybackSpeedSliderLabel = uilabel(app.EffectandSampleModificationPanel);
            app.PlaybackSpeedSliderLabel.Text = 'Playback Speed';
            app.PlaybackSpeedSlider = uislider(app.EffectandSampleModificationPanel);
            app.PlaybackSpeedSlider.Limits = [0.5 2];
            app.PlaybackSpeedSlider.Value = 1;
            app.DelayKnobLabel = uilabel(app.EffectandSampleModificationPanel);
            app.DelayKnobLabel.Text = 'Delay';
            app.DelayKnob = uiknob(app.EffectandSampleModificationPanel, 'continuous');
            app.DelayKnob.Limits = [0 3];
            app.FeedbackKnobLabel = uilabel(app.EffectandSampleModificationPanel);
            app.FeedbackKnobLabel.Text = 'Feedback';
            app.FeedbackKnob = uiknob(app.EffectandSampleModificationPanel, 'continuous');
            app.FeedbackKnob.Limits = [0 5];
            app.FeedbackKnob.Value = 1;
            app.EqualizerPanel = uipanel(app.BasicModeTab);
            app.EqualizerPanel.Title = 'Equalizer';
            app.HzLabel = uilabel(app.EqualizerPanel);
            app.HzLabel.Text = '170Hz';
            app.HzSlider_2 = uislider(app.EqualizerPanel);
            app.HzSlider_2.Limits = [-3 3];
            app.HzSlider_2.ValueChangedFcn = createCallbackFcn(app, @HzSlider_2ValueChanged, true);
            app.HzLabel_2 = uilabel(app.EqualizerPanel);
            app.HzLabel_2.Text = '310Hz';
            app.HzSlider_3 = uislider(app.EqualizerPanel);
            app.HzSlider_3.Limits = [-3 3];
            app.HzSlider_3.ValueChangedFcn = createCallbackFcn(app, @HzSlider_3ValueChanged, true);
            app.HzLabel_4 = uilabel(app.EqualizerPanel);
            app.HzLabel_4.Text = '600Hz';
            app.HzSlider_4 = uislider(app.EqualizerPanel);
            app.HzSlider_4.Limits = [-3 3];
            app.HzSlider_4.ValueChangedFcn = createCallbackFcn(app, @HzSlider_4ValueChanged, true);
            app.KHzSliderLabel = uilabel(app.EqualizerPanel);
            app.KHzSliderLabel.Text = '1KHz';
            app.KHzSlider = uislider(app.EqualizerPanel);
            app.KHzSlider.Limits = [-3 3];
            app.KHzSlider.ValueChangedFcn = createCallbackFcn(app, @KHzSliderValueChanged, true);
            app.KHzSlider_2Label = uilabel(app.EqualizerPanel);
            app.KHzSlider_2Label.Text = '3KHz';
            app.KHzSlider_2 = uislider(app.EqualizerPanel);
            app.KHzSlider_2.Limits = [-3 3];
            app.KHzSlider_2.ValueChangedFcn = createCallbackFcn(app, @KHzSlider_2ValueChanged, true);
            app.HzLabel_3 = uilabel(app.EqualizerPanel);
            app.HzLabel_3.Text = '60Hz';
            app.HzSlider_7 = uislider(app.EqualizerPanel);
            app.HzSlider_7.Limits = [-3 3];
            app.HzSlider_7.ValueChangedFcn = createCallbackFcn(app, @HzSlider_7ValueChanged, true);
            app.PlayCheckBox_2 = uicheckbox(app.EqualizerPanel);
            app.PlayCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @PlayCheckBox_2ValueChanged, true);
            app.PlayCheckBox_2.Text = 'Play';
            app.SoundNoDropDown_5Label = uilabel(app.EqualizerPanel);
            app.SoundNoDropDown_5Label.Text = 'Sound No.';
            app.SoundNoDropDown_5 = uidropdown(app.EqualizerPanel);
            app.SoundNoDropDown_5.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SoundNoDropDown_5.ValueChangedFcn = createCallbackFcn(app, @SoundNoDropDown_5ValueChanged, true);
            app.SoundNoDropDown_5.Value = 'Sound 1';
            app.ChoppingPanel = uipanel(app.BasicModeTab);
            app.ChoppingPanel.Title = 'Chopping';
            app.SoundSliderLabel = uilabel(app.ChoppingPanel);
            app.SoundSliderLabel.Text = 'Sound';
            app.SoundSlider = uislider(app.ChoppingPanel, 'range');
            app.SoundSlider.ValueChangedFcn = createCallbackFcn(app, @SoundSliderValueChanged, true);
            app.SoundNoDropDown_2Label = uilabel(app.ChoppingPanel);
            app.SoundNoDropDown_2Label.Text = 'Sound No.';
            app.SoundNoDropDown_2 = uidropdown(app.ChoppingPanel);
            app.SoundNoDropDown_2.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SoundNoDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SoundNoDropDown_2ValueChanged, true);
            app.SoundNoDropDown_2.Value = 'Sound 1';
            app.SaveButton = uibutton(app.ChoppingPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PlayButton = uibutton(app.ChoppingPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.SavingPlaceDropDown_2 = uidropdown(app.ChoppingPanel);
            app.SavingPlaceDropDown_2.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SavingPlaceDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SavingPlaceDropDown_2ValueChanged, true);
            app.SavingPlaceDropDown_2.Value = 'Sound 1';
            app.SavingPlaceDropDown_2Label = uilabel(app.ChoppingPanel);
            app.SavingPlaceDropDown_2Label.Text = 'Saving Place';
            app.SynthesizerPanel = uipanel(app.BasicModeTab);
            app.SynthesizerPanel.Title = 'Synthesizer';
            app.SaveButton_2 = uibutton(app.SynthesizerPanel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.SavingPlaceDropDown = uidropdown(app.SynthesizerPanel);
            app.SavingPlaceDropDown.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SavingPlaceDropDown.ValueChangedFcn = createCallbackFcn(app, @SavingPlaceDropDownValueChanged, true);
            app.SavingPlaceDropDown.Value = 'Sound 1';
            app.SavingPlaceDropDownLabel = uilabel(app.SynthesizerPanel);
            app.SavingPlaceDropDownLabel.Text = 'Saving Place';
            app.PlayButton_2 = uibutton(app.SynthesizerPanel, 'state');
            app.PlayButton_2.ValueChangedFcn = createCallbackFcn(app, @PlayButton_2ValueChanged, true);
            app.PlayButton_2.Text = 'Play';
            app.SineWaveCheckBox = uicheckbox(app.SynthesizerPanel);
            app.SineWaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @SineWaveCheckBoxValueChanged, true);
            app.SineWaveCheckBox.Text = 'Sine Wave';
            app.PulseWaveCheckBox = uicheckbox(app.SynthesizerPanel);
            app.PulseWaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @PulseWaveCheckBoxValueChanged, true);
            app.PulseWaveCheckBox.Text = 'Pulse Wave';
            app.SawWaveCheckBox = uicheckbox(app.SynthesizerPanel);
            app.SawWaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @SawWaveCheckBoxValueChanged, true);
            app.SawWaveCheckBox.Text = 'Saw Wave';
            app.DoButton = uibutton(app.SynthesizerPanel, 'state');
            app.DoButton.ValueChangedFcn = createCallbackFcn(app, @DoButtonValueChanged, true);
            app.DoButton.Text = 'Do';
            app.ReButton = uibutton(app.SynthesizerPanel, 'state');
            app.ReButton.ValueChangedFcn = createCallbackFcn(app, @ReButtonValueChanged, true);
            app.ReButton.Text = 'Re';
            app.MiButton = uibutton(app.SynthesizerPanel, 'state');
            app.MiButton.ValueChangedFcn = createCallbackFcn(app, @MiButtonValueChanged, true);
            app.MiButton.Text = 'Mi';
            app.FaButton = uibutton(app.SynthesizerPanel, 'state');
            app.FaButton.ValueChangedFcn = createCallbackFcn(app, @FaButtonValueChanged, true);
            app.FaButton.Text = 'Fa';
            app.SolButton = uibutton(app.SynthesizerPanel, 'state');
            app.SolButton.ValueChangedFcn = createCallbackFcn(app, @SolButtonValueChanged, true);
            app.SolButton.Text = 'Sol';
            app.LaButton = uibutton(app.SynthesizerPanel, 'state');
            app.LaButton.ValueChangedFcn = createCallbackFcn(app, @LaButtonValueChanged, true);
            app.LaButton.Text = 'La';
            app.LaunchPadTab = uitab(app.TabGroup);
            app.LaunchPadTab.Title = 'Launch Pad';
            app.SaveButton_3 = uibutton(app.LaunchPadTab, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Text = 'Save';
            app.SavingPlaceDropDown_3 = uidropdown(app.LaunchPadTab);
            app.SavingPlaceDropDown_3.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SavingPlaceDropDown_3.ValueChangedFcn = createCallbackFcn(app, @SavingPlaceDropDown_3ValueChanged, true);
            app.SavingPlaceDropDown_3.Value = 'Sound 1';
            app.SavingPlaceDropDown_3Label = uilabel(app.LaunchPadTab);
            app.SavingPlaceDropDown_3Label.Text = 'Saving Place';
            app.Sound1Button_2 = uibutton(app.LaunchPadTab, 'state');
            app.Sound1Button_2.ValueChangedFcn = createCallbackFcn(app, @Sound1Button_2ValueChanged, true);
            app.Sound1Button_2.Text = 'Sound1';
            app.Sound2Button_2 = uibutton(app.LaunchPadTab, 'state');
            app.Sound2Button_2.ValueChangedFcn = createCallbackFcn(app, @Sound2Button_2ValueChanged, true);
            app.Sound2Button_2.Text = 'Sound2';
            app.Sound3Button_2 = uibutton(app.LaunchPadTab, 'state');
            app.Sound3Button_2.ValueChangedFcn = createCallbackFcn(app, @Sound3Button_2ValueChanged, true);
            app.Sound3Button_2.Text = 'Sound3';
            app.Sound4Button_2 = uibutton(app.LaunchPadTab, 'state');
            app.Sound4Button_2.ValueChangedFcn = createCallbackFcn(app, @Sound4Button_2ValueChanged, true);
            app.Sound4Button_2.Text = 'Sound4';
            app.Sound5Button_2 = uibutton(app.LaunchPadTab, 'state');
            app.Sound5Button_2.ValueChangedFcn = createCallbackFcn(app, @Sound5Button_2ValueChanged, true);
            app.Sound5Button_2.Text = 'Sound5';
            app.Sound6Button_2 = uibutton(app.LaunchPadTab, 'state');
            app.Sound6Button_2.ValueChangedFcn = createCallbackFcn(app, @Sound6Button_2ValueChanged, true);
            app.Sound6Button_2.Text = 'Sound6';
            app.Sound7Button_2 = uibutton(app.LaunchPadTab, 'state');
            app.Sound7Button_2.ValueChangedFcn = createCallbackFcn(app, @Sound7Button_2ValueChanged, true);
            app.Sound7Button_2.Text = 'Sound7';
            app.Sound9Button_2 = uibutton(app.LaunchPadTab, 'state');
            app.Sound9Button_2.ValueChangedFcn = createCallbackFcn(app, @Sound9Button_2ValueChanged, true);
            app.Sound9Button_2.Text = 'Sound9';
            app.Sound8Button_2 = uibutton(app.LaunchPadTab, 'state');
            app.Sound8Button_2.ValueChangedFcn = createCallbackFcn(app, @Sound8Button_2ValueChanged, true);
            app.Sound8Button_2.Text = 'Sound8';
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            app.MessageTextArea.Value = {' '};
            app.SoundPanel = uipanel(app.UIFigure);
            app.SoundPanel.Title = 'Sound';
            app.Sound1Button = uibutton(app.SoundPanel, 'push');
            app.Sound1Button.ButtonPushedFcn = createCallbackFcn(app, @Sound1ButtonPushed, true);
            app.Sound1Button.Text = 'Sound1';
            app.Sound2Button = uibutton(app.SoundPanel, 'push');
            app.Sound2Button.ButtonPushedFcn = createCallbackFcn(app, @Sound2ButtonPushed, true);
            app.Sound2Button.Text = 'Sound2';
            app.Sound3Button = uibutton(app.SoundPanel, 'push');
            app.Sound3Button.ButtonPushedFcn = createCallbackFcn(app, @Sound3ButtonPushed, true);
            app.Sound3Button.Text = 'Sound3';
            app.Sound4Button = uibutton(app.SoundPanel, 'push');
            app.Sound4Button.ButtonPushedFcn = createCallbackFcn(app, @Sound4ButtonPushed, true);
            app.Sound4Button.Text = 'Sound4';
            app.Sound5Button = uibutton(app.SoundPanel, 'push');
            app.Sound5Button.ButtonPushedFcn = createCallbackFcn(app, @Sound5ButtonPushed, true);
            app.Sound5Button.Text = 'Sound5';
            app.Sound6Button = uibutton(app.SoundPanel, 'push');
            app.Sound6Button.ButtonPushedFcn = createCallbackFcn(app, @Sound6ButtonPushed, true);
            app.Sound6Button.Text = 'Sound6';
            app.Sound7Button = uibutton(app.SoundPanel, 'push');
            app.Sound7Button.ButtonPushedFcn = createCallbackFcn(app, @Sound7ButtonPushed, true);
            app.Sound7Button.Text = 'Sound7';
            app.Sound8Button = uibutton(app.SoundPanel, 'push');
            app.Sound8Button.ButtonPushedFcn = createCallbackFcn(app, @Sound8ButtonPushed, true);
            app.Sound8Button.Text = 'Sound8';
            app.Sound9Button = uibutton(app.SoundPanel, 'push');
            app.Sound9Button.ButtonPushedFcn = createCallbackFcn(app, @Sound9ButtonPushed, true);
            app.Sound9Button.Text = 'Sound9';
            app.EditField = uieditfield(app.SoundPanel, 'text');
            app.EditField_2 = uieditfield(app.SoundPanel, 'text');
            app.EditField_3 = uieditfield(app.SoundPanel, 'text');
            app.EditField_4 = uieditfield(app.SoundPanel, 'text');
            app.EditField_5 = uieditfield(app.SoundPanel, 'text');
            app.EditField_6 = uieditfield(app.SoundPanel, 'text');
            app.EditField_7 = uieditfield(app.SoundPanel, 'text');
            app.EditField_8 = uieditfield(app.SoundPanel, 'text');
            app.EditField_9 = uieditfield(app.SoundPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TeamProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end