classdef TeamProject2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SoundPanel                    matlab.ui.container.Panel
        Sound9Button                  matlab.ui.control.Button
        Sound8Button                  matlab.ui.control.Button
        Sound7Button                  matlab.ui.control.Button
        Sound6Button                  matlab.ui.control.Button
        Sound5Button                  matlab.ui.control.Button
        Sound4Button                  matlab.ui.control.Button
        Sound3Button                  matlab.ui.control.Button
        Sound2Button                  matlab.ui.control.Button
        Sound1Button                  matlab.ui.control.Button
        MessageTextArea               matlab.ui.control.TextArea
        MessageTextAreaLabel          matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        BasicModeTab                  matlab.ui.container.Tab
        SynthesizerPanel              matlab.ui.container.Panel
        PitchDropDown                 matlab.ui.control.DropDown
        PitchDropDownLabel            matlab.ui.control.Label
        SawWaveCheckBox               matlab.ui.control.CheckBox
        TriangleWaveCheckBox          matlab.ui.control.CheckBox
        PulseWaveCheckBox             matlab.ui.control.CheckBox
        SineWaveCheckBox              matlab.ui.control.CheckBox
        PlayButton_2                  matlab.ui.control.StateButton
        SavingPlaceDropDownLabel      matlab.ui.control.Label
        SavingPlaceDropDown           matlab.ui.control.DropDown
        SoundNoDropDown_3Label        matlab.ui.control.Label
        SoundNoDropDown_3             matlab.ui.control.DropDown
        SaveButton_2                  matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        ChoppingPanel                 matlab.ui.container.Panel
        SavingPlaceDropDown_2Label    matlab.ui.control.Label
        SavingPlaceDropDown_2         matlab.ui.control.DropDown
        PlayButton                    matlab.ui.control.StateButton
        SaveButton                    matlab.ui.control.Button
        SoundNoDropDown_2             matlab.ui.control.DropDown
        SoundNoDropDown_2Label        matlab.ui.control.Label
        SoundSlider                   matlab.ui.control.RangeSlider
        SoundSliderLabel              matlab.ui.control.Label
        EqualizerPanel                matlab.ui.container.Panel
        SoundNoDropDown_5             matlab.ui.control.DropDown
        SoundNoDropDown_5Label        matlab.ui.control.Label
        PlayCheckBox_2                matlab.ui.control.CheckBox
        HzSlider_7                    matlab.ui.control.Slider
        HzLabel_3                     matlab.ui.control.Label
        KHzSlider_2                   matlab.ui.control.Slider
        KHzSlider_2Label              matlab.ui.control.Label
        KHzSlider                     matlab.ui.control.Slider
        KHzSliderLabel                matlab.ui.control.Label
        HzSlider_4                    matlab.ui.control.Slider
        HzLabel_4                     matlab.ui.control.Label
        HzSlider_3                    matlab.ui.control.Slider
        HzLabel_2                     matlab.ui.control.Label
        HzSlider_2                    matlab.ui.control.Slider
        HzLabel                       matlab.ui.control.Label
        EffectandSampleModificationPanel  matlab.ui.container.Panel
        EchoKnob                      matlab.ui.control.Knob
        EchoKnobLabel                 matlab.ui.control.Label
        PlaybackSpeedSlider           matlab.ui.control.Slider
        PlaybackSpeedSliderLabel      matlab.ui.control.Label
        PlaybackDirectionSwitch       matlab.ui.control.Switch
        PlaybackDirectionSwitchLabel  matlab.ui.control.Label
        PlaySwitch                    matlab.ui.control.ToggleSwitch
        PlaySwitchLabel               matlab.ui.control.Label
        VolumeKnob                    matlab.ui.control.Knob
        VolumeKnobLabel               matlab.ui.control.Label
        SoundNoDropDown               matlab.ui.control.DropDown
        SoundNoDropDownLabel          matlab.ui.control.Label
        LunchPadTab                   matlab.ui.container.Tab
        StartPointSlider              matlab.ui.control.Slider
        StartPointSliderLabel         matlab.ui.control.Label
        PlayButton_3                  matlab.ui.control.Button
        SavingPlaceDropDown_3Label    matlab.ui.control.Label
        SavingPlaceDropDown_3         matlab.ui.control.DropDown
        SaveButton_3                  matlab.ui.control.Button
        SoundNoDropDown_4Label        matlab.ui.control.Label
        SoundNoDropDown_4             matlab.ui.control.DropDown
        DrumButton                    matlab.ui.control.Button
        BassButton                    matlab.ui.control.Button
        MelodyButton                  matlab.ui.control.Button
        MelodyButton_5                matlab.ui.control.Button
        DrumButton_2                  matlab.ui.control.Button
        BassButton_2                  matlab.ui.control.Button
        MelodyButton_2                matlab.ui.control.Button
        MelodyButton_6                matlab.ui.control.Button
        DrumButton_3                  matlab.ui.control.Button
        BassButton_3                  matlab.ui.control.Button
        MelodyButton_7                matlab.ui.control.Button
        DrumButton_4                  matlab.ui.control.Button
        BassButton_4                  matlab.ui.control.Button
        MelodyButton_4                matlab.ui.control.Button
        MelodyButton_8                matlab.ui.control.Button
        MelodyButton_3                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        audio = struct('yValue', {}, 'frequency', {}); 
        chopSegment
        chopFs
        yValEq
        fsEq
        yValChop
        fsChop
        startIndexChop
        endIndexChop
        savingPlaceChop
        gain1
        gain2
        gain3
        gain4
        gain5
        gain6
    end
    
    methods (Access = private)
        
        function [yvalue, Fs] = soundInput(app)
            [filename,filepath] =uigetfile({'*.wav; *.mp3'}, 'Select File to Open');
            fullname = [filepath, filename];
            [firstValue, secondValue] = audioread(fullname);
            yvalue = firstValue;
            Fs = secondValue; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Sound1Button
        function Sound1ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(1).yValue = y; 
            app.audio(1).frequency = Fs; 
            disp("audio_1 setting finish");
            app.Sound1Button.FontColor = [0, 0.8, 0]; 
        end

        % Button pushed function: Sound2Button
        function Sound2ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(2).yValue = y; 
            app.audio(2).frequency = Fs; 
            disp("audio_2 setting finish");
            app.Sound2Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound3Button
        function Sound3ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(3).yValue = y; 
            app.audio(3).frequency = Fs; 
            disp("audio_3 setting finish");
            app.Sound3Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound4Button
        function Sound4ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(4).yValue = y; 
            app.audio(4).frequency = Fs; 
            disp("audio_4 setting finish");
            app.Sound4Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound5Button
        function Sound5ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(5).yValue = y; 
            app.audio(5).frequency = Fs; 
            disp("audio_5 setting finish");
            app.Sound5Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound6Button
        function Sound6ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(6).yValue = y; 
            app.audio(6).frequency = Fs; 
            disp("audio_6 setting finish");
            app.Sound6Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound7Button
        function Sound7ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(7).yValue = y; 
            app.audio(7).frequency = Fs; 
            disp("audio_7 setting finish");
            app.Sound7Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound8Button
        function Sound8ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(8).yValue = y; 
            app.audio(8).frequency = Fs; 
            disp("audio_8 setting finish");
            app.Sound8Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound9Button
        function Sound9ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(9).yValue = y; 
            app.audio(9).frequency = Fs; 
            disp("audio_9 setting finish");
            app.Sound9Button.FontColor = [0, 0.8, 0];
        end

        % Value changed function: PlayCheckBox_2
        function PlayCheckBox_2ValueChanged(app, event)
            value = app.PlayCheckBox_2.Value;
            %equalizedAudio = (app.yValEq).*(10^(app.gain1/60)).*(10^(app.gain2/170)).*(10^(app.gain3/310)).*(10^(app.gain4/600)).*(10^(app.gain5/1000)).*(10^(app.gain6/3000));
            if (value == true)
                sound(app.yValEq, app.fsEq, 16);
            else
                clear sound;
            end
        end

        % Value changing function: HzSlider_7
        function HzSlider_7ValueChanging(app, event)
            changingValue = event.Value;
        end

        % Value changing function: HzSlider_2
        function HzSlider_2ValueChanging(app, event)
            changingValue = event.Value;
        end

        % Value changing function: HzSlider_3
        function HzSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: HzSlider_4
        function HzSlider_4ValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: KHzSlider
        function KHzSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: KHzSlider_2
        function KHzSlider_2ValueChanging(app, event)
            changingValue = event.Value;
        end

        % Value changing function: SoundSlider
        function SoundSliderValueChanging(app, event)
            changingValue = event.Value;
        end

        % Clicked callback: SoundNoDropDown_5
        function SoundNoDropDown_5Clicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if (value == true)
                sound(app.chopSegment, app.fsChop, 16);
            else
                clear sound;
            end
        end

        % Value changed function: SoundSlider
        function SoundSliderValueChanged(app, event)
            value = app.SoundSlider.Value;

            % Read the audio file
            %[inputAudio, app.chopFs] = audioread(app.audio(1));

            app.startIndexChop = length(app.yValChop)*(value(1)/100);
            app.endIndexChop = length(app.yValChop)*(value(2)/100);


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            % Write the segment to a new file
            app.chopSegment = app.yValChop(app.startIndexChop:app.endIndexChop, :);

            if (app.savingPlaceChop == 1)
                app.audio(1).yValue = app.chopSegment;
                app.audio(1).frequency = app.fsChop; 
                disp("audio_1 setting finish");
                app.Sound1Button.FontColor = [0, 0.8, 0];
            elseif (app.savingPlaceChop == 2)
                app.audio(2).yValue = app.chopSegment;
                app.audio(2).frequency = app.fsChop; 
                disp("audio_2 setting finish");
                app.Sound2Button.FontColor = [0, 0.8, 0];
            elseif (app.savingPlaceChop == 3)
                app.audio(3).yValue = app.chopSegment;
                app.audio(3).frequency = app.fsChop; 
                disp("audio_3 setting finish");
                app.Sound3Button.FontColor = [0, 0.8, 0];
            elseif (app.savingPlaceChop == 4)
                app.audio(4).yValue = app.chopSegment;
                app.audio(4).frequency = app.fsChop; 
                disp("audio_4 setting finish");
                app.Sound4Button.FontColor = [0, 0.8, 0];
            elseif (app.savingPlaceChop == 5)
                app.audio(5).yValue = app.chopSegment;
                app.audio(5).frequency = app.fsChop; 
                disp("audio_5 setting finish");
                app.Sound5Button.FontColor = [0, 0.8, 0];
            elseif (app.savingPlaceChop == 6)
                app.audio(6).yValue = app.chopSegment;
                app.audio(6).frequency = app.fsChop; 
                disp("audio_6 setting finish");
                app.Sound6Button.FontColor = [0, 0.8, 0];
            elseif (app.savingPlaceChop == 7)
                app.audio(7).yValue = app.chopSegment;
                app.audio(7).frequency = app.fsChop; 
                disp("audio_7 setting finish");
                app.Sound7Button.FontColor = [0, 0.8, 0];
            elseif (app.savingPlaceChop == 8)
                app.audio(8).yValue = app.chopSegment;
                app.audio(8).frequency = app.fsChop; 
                disp("audio_8 setting finish");
                app.Sound8Button.FontColor = [0, 0.8, 0];
            else 
                app.audio(9).yValue = app.chopSegment;
                app.audio(9).frequency = app.fsChop; 
                disp("audio_9 setting finish");
                app.Sound9Button.FontColor = [0, 0.8, 0];
            end


            %filename = 'segment_4.wav';
            %audiowrite(filename, app.chopSegment, app.fsChop);
            %[app.chopSegment, app.fsChop] = audioread(filename);
        end

        % Clicked callback: SoundNoDropDown_2
        function SoundNoDropDown_2Clicked(app, event)
            item = event.InteractionInformation.Item;
        end

        % Value changed function: SoundNoDropDown_2
        function SoundNoDropDown_2ValueChanged(app, event)
            value = app.SoundNoDropDown_2.Value;
            if (value == 'Sound 1')
                app.yValChop = app.audio(1).yValue;
                app.fsChop = app.audio(1).frequency;
            elseif (value == 'Sound 2')
                app.yValChop = app.audio(2).yValue;
                app.fsChop = app.audio(2).frequency;
            elseif (value == 'Sound 3')
                app.yValChop = app.audio(3).yValue;
                app.fsChop = app.audio(3).frequency;
            elseif (value == 'Sound 4')
                app.yValChop = app.audio(4).yValue;
                app.fsChop = app.audio(4).frequency;
            elseif (value == 'Sound 5')
                app.yValChop = app.audio(5).yValue;
                app.fsChop = app.audio(5).frequency;
            elseif (value == 'Sound 6')
                app.yValChop = app.audio(6).yValue;
                app.fsChop = app.audio(6).frequency;
            elseif (value == 'Sound 7')
                app.yValChop = app.audio(7).yValue;
                app.fsChop = app.audio(7).frequency;
            elseif (value == 'Sound 8')
                app.yValChop = app.audio(8).yValue;
                app.fsChop= app.audio(8).frequency;
            else
                app.yValChop = app.audio(9).yValue;
                app.fsChop = app.audio(9).frequency;
            end
        end

        % Value changed function: SoundNoDropDown_5
        function SoundNoDropDown_5ValueChanged(app, event)
            value = app.SoundNoDropDown_5.Value;
            if (value == 'Sound 1')
                app.yValEq = app.audio(1).yValue;
                app.fsEq = app.audio(1).frequency;
            elseif (value == 'Sound 2')
                app.yValEq = app.audio(2).yValue;
                app.fsEq = app.audio(2).frequency;
            elseif (value == 'Sound 3')
                app.yValEq = app.audio(3).yValue;
                app.fsEq = app.audio(3).frequency;
            elseif (value == 'Sound 4')
                app.yValEq = app.audio(4).yValue;
                app.fsEq = app.audio(4).frequency;
            elseif (value == 'Sound 5')
                app.yValEq = app.audio(5).yValue;
                app.fsEq = app.audio(5).frequency;
            elseif (value == 'Sound 6')
                app.yValEq = app.audio(6).yValue;
                app.fsEq = app.audio(6).frequency;
            elseif (value == 'Sound 7')
                app.yValEq = app.audio(7).yValue;
                app.fsEq = app.audio(7).frequency;
            elseif (value == 'Sound 8')
                app.yValEq = app.audio(8).yValue;
                app.fsEq = app.audio(8).frequency;
            else
                app.yValEq = app.audio(9).yValue;
                app.fsEq = app.audio(9).frequency;
            end
        end

        % Value changed function: SavingPlaceDropDown_2
        function SavingPlaceDropDown_2ValueChanged(app, event)
            value = app.SavingPlaceDropDown_2.Value;
            if (value == 'Sound 1')
                app.savingPlaceChop = 1;
            elseif (value == 'Sound 2')
                app.savingPlaceChop = 2;
            elseif (value == 'Sound 3')
                app.savingPlaceChop = 3;
            elseif (value == 'Sound 4')
                app.savingPlaceChop = 4;
            elseif (value == 'Sound 5')
                app.savingPlaceChop = 5;
            elseif (value == 'Sound 6')
                app.savingPlaceChop = 6;
            elseif (value == 'Sound 7')
                app.savingPlaceChop = 7;
            elseif (value == 'Sound 8')
                app.savingPlaceChop = 8;
            else
                app.savingPlaceChop = 9;
            end
        end

        % Value changed function: HzSlider_7
        function HzSlider_7ValueChanged(app, event)
            value = app.HzSlider_7.Value;
            app.gain1 = value;
        end

        % Value changed function: HzSlider_2
        function HzSlider_2ValueChanged(app, event)
            value = app.HzSlider_2.Value;
            app.gain2 = value;
        end

        % Value changed function: HzSlider_3
        function HzSlider_3ValueChanged(app, event)
            value = app.HzSlider_3.Value;
            app.gain3 = value;
        end

        % Value changed function: HzSlider_4
        function HzSlider_4ValueChanged(app, event)
            value = app.HzSlider_4.Value;
            app.gain4 = value;
        end

        % Value changed function: KHzSlider
        function KHzSliderValueChanged(app, event)
            value = app.KHzSlider.Value;
            app.gain5 = value;
        end

        % Value changed function: KHzSlider_2
        function KHzSlider_2ValueChanged(app, event)
            value = app.KHzSlider_2.Value;
            app.gain6 = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sound Wave')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicModeTab = uitab(app.TabGroup);
            app.BasicModeTab.Title = 'Basic Mode';
            app.EffectandSampleModificationPanel = uipanel(app.BasicModeTab);
            app.EffectandSampleModificationPanel.Title = 'Effect and Sample Modification ';
            app.SoundNoDropDownLabel = uilabel(app.EffectandSampleModificationPanel);
            app.SoundNoDropDownLabel.Text = 'Sound No.';
            app.SoundNoDropDown = uidropdown(app.EffectandSampleModificationPanel);
            app.SoundNoDropDown.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SoundNoDropDown.Value = 'Sound 1';
            app.VolumeKnobLabel = uilabel(app.EffectandSampleModificationPanel);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.EffectandSampleModificationPanel, 'continuous');
            app.PlaySwitchLabel = uilabel(app.EffectandSampleModificationPanel);
            app.PlaySwitchLabel.Text = 'Play';
            app.PlaySwitch = uiswitch(app.EffectandSampleModificationPanel, 'toggle');
            app.PlaybackDirectionSwitchLabel = uilabel(app.EffectandSampleModificationPanel);
            app.PlaybackDirectionSwitchLabel.Text = 'Playback Direction';
            app.PlaybackDirectionSwitch = uiswitch(app.EffectandSampleModificationPanel, 'slider');
            app.PlaybackDirectionSwitch.Items = {'Forward', 'Backward'};
            app.PlaybackDirectionSwitch.Value = 'Forward';
            app.PlaybackSpeedSliderLabel = uilabel(app.EffectandSampleModificationPanel);
            app.PlaybackSpeedSliderLabel.Text = 'Playback Speed';
            app.PlaybackSpeedSlider = uislider(app.EffectandSampleModificationPanel);
            app.PlaybackSpeedSlider.Limits = [0.5 2];
            app.PlaybackSpeedSlider.Value = 0.5;
            app.EchoKnobLabel = uilabel(app.EffectandSampleModificationPanel);
            app.EchoKnobLabel.Text = 'Echo';
            app.EchoKnob = uiknob(app.EffectandSampleModificationPanel, 'continuous');
            app.EqualizerPanel = uipanel(app.BasicModeTab);
            app.EqualizerPanel.Title = 'Equalizer';
            app.HzLabel = uilabel(app.EqualizerPanel);
            app.HzLabel.Text = '170Hz';
            app.HzSlider_2 = uislider(app.EqualizerPanel);
            app.HzSlider_2.Limits = [-3 3];
            app.HzSlider_2.ValueChangedFcn = createCallbackFcn(app, @HzSlider_2ValueChanged, true);
            app.HzSlider_2.ValueChangingFcn = createCallbackFcn(app, @HzSlider_2ValueChanging, true);
            app.HzLabel_2 = uilabel(app.EqualizerPanel);
            app.HzLabel_2.Text = '310Hz';
            app.HzSlider_3 = uislider(app.EqualizerPanel);
            app.HzSlider_3.Limits = [-3 3];
            app.HzSlider_3.ValueChangedFcn = createCallbackFcn(app, @HzSlider_3ValueChanged, true);
            app.HzSlider_3.ValueChangingFcn = createCallbackFcn(app, @HzSlider_3ValueChanging, true);
            app.HzLabel_4 = uilabel(app.EqualizerPanel);
            app.HzLabel_4.Text = '600Hz';
            app.HzSlider_4 = uislider(app.EqualizerPanel);
            app.HzSlider_4.Limits = [-3 3];
            app.HzSlider_4.ValueChangedFcn = createCallbackFcn(app, @HzSlider_4ValueChanged, true);
            app.HzSlider_4.ValueChangingFcn = createCallbackFcn(app, @HzSlider_4ValueChanging, true);
            app.KHzSliderLabel = uilabel(app.EqualizerPanel);
            app.KHzSliderLabel.Text = '1KHz';
            app.KHzSlider = uislider(app.EqualizerPanel);
            app.KHzSlider.Limits = [-3 3];
            app.KHzSlider.ValueChangedFcn = createCallbackFcn(app, @KHzSliderValueChanged, true);
            app.KHzSlider.ValueChangingFcn = createCallbackFcn(app, @KHzSliderValueChanging, true);
            app.KHzSlider_2Label = uilabel(app.EqualizerPanel);
            app.KHzSlider_2Label.Text = '3KHz';
            app.KHzSlider_2 = uislider(app.EqualizerPanel);
            app.KHzSlider_2.Limits = [-3 3];
            app.KHzSlider_2.ValueChangedFcn = createCallbackFcn(app, @KHzSlider_2ValueChanged, true);
            app.KHzSlider_2.ValueChangingFcn = createCallbackFcn(app, @KHzSlider_2ValueChanging, true);
            app.HzLabel_3 = uilabel(app.EqualizerPanel);
            app.HzLabel_3.Text = '60Hz';
            app.HzSlider_7 = uislider(app.EqualizerPanel);
            app.HzSlider_7.Limits = [-3 3];
            app.HzSlider_7.ValueChangedFcn = createCallbackFcn(app, @HzSlider_7ValueChanged, true);
            app.HzSlider_7.ValueChangingFcn = createCallbackFcn(app, @HzSlider_7ValueChanging, true);
            app.PlayCheckBox_2 = uicheckbox(app.EqualizerPanel);
            app.PlayCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @PlayCheckBox_2ValueChanged, true);
            app.PlayCheckBox_2.Text = 'Play';
            app.SoundNoDropDown_5Label = uilabel(app.EqualizerPanel);
            app.SoundNoDropDown_5Label.Text = 'Sound No.';
            app.SoundNoDropDown_5 = uidropdown(app.EqualizerPanel);
            app.SoundNoDropDown_5.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SoundNoDropDown_5.ValueChangedFcn = createCallbackFcn(app, @SoundNoDropDown_5ValueChanged, true);
            app.SoundNoDropDown_5.ClickedFcn = createCallbackFcn(app, @SoundNoDropDown_5Clicked, true);
            app.SoundNoDropDown_5.Value = 'Sound 1';
            app.ChoppingPanel = uipanel(app.BasicModeTab);
            app.ChoppingPanel.Title = 'Chopping';
            app.SoundSliderLabel = uilabel(app.ChoppingPanel);
            app.SoundSliderLabel.Text = 'Sound';
            app.SoundSlider = uislider(app.ChoppingPanel, 'range');
            app.SoundSlider.ValueChangedFcn = createCallbackFcn(app, @SoundSliderValueChanged, true);
            app.SoundSlider.ValueChangingFcn = createCallbackFcn(app, @SoundSliderValueChanging, true);
            app.SoundNoDropDown_2Label = uilabel(app.ChoppingPanel);
            app.SoundNoDropDown_2Label.Text = 'Sound No.';
            app.SoundNoDropDown_2 = uidropdown(app.ChoppingPanel);
            app.SoundNoDropDown_2.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SoundNoDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SoundNoDropDown_2ValueChanged, true);
            app.SoundNoDropDown_2.ClickedFcn = createCallbackFcn(app, @SoundNoDropDown_2Clicked, true);
            app.SoundNoDropDown_2.Value = 'Sound 1';
            app.SaveButton = uibutton(app.ChoppingPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PlayButton = uibutton(app.ChoppingPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.SavingPlaceDropDown_2 = uidropdown(app.ChoppingPanel);
            app.SavingPlaceDropDown_2.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SavingPlaceDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SavingPlaceDropDown_2ValueChanged, true);
            app.SavingPlaceDropDown_2.Value = 'Sound 1';
            app.SavingPlaceDropDown_2Label = uilabel(app.ChoppingPanel);
            app.SavingPlaceDropDown_2Label.Text = 'Saving Place';
            app.SynthesizerPanel = uipanel(app.BasicModeTab);
            app.SynthesizerPanel.Title = 'Synthesizer';
            app.UIAxes2 = uiaxes(app.SynthesizerPanel);
            title(app.UIAxes2, 'Synthesized Wave')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.SaveButton_2 = uibutton(app.SynthesizerPanel, 'push');
            app.SaveButton_2.Text = 'Save';
            app.SoundNoDropDown_3 = uidropdown(app.SynthesizerPanel);
            app.SoundNoDropDown_3.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SoundNoDropDown_3.Value = 'Sound 1';
            app.SoundNoDropDown_3Label = uilabel(app.SynthesizerPanel);
            app.SoundNoDropDown_3Label.Text = 'Sound No.';
            app.SavingPlaceDropDown = uidropdown(app.SynthesizerPanel);
            app.SavingPlaceDropDown.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SavingPlaceDropDown.Value = 'Sound 1';
            app.SavingPlaceDropDownLabel = uilabel(app.SynthesizerPanel);
            app.SavingPlaceDropDownLabel.Text = 'Saving Place';
            app.PlayButton_2 = uibutton(app.SynthesizerPanel, 'state');
            app.PlayButton_2.Text = 'Play';
            app.SineWaveCheckBox = uicheckbox(app.SynthesizerPanel);
            app.SineWaveCheckBox.Text = 'Sine Wave';
            app.PulseWaveCheckBox = uicheckbox(app.SynthesizerPanel);
            app.PulseWaveCheckBox.Text = 'Pulse Wave';
            app.TriangleWaveCheckBox = uicheckbox(app.SynthesizerPanel);
            app.TriangleWaveCheckBox.Text = 'Triangle Wave';
            app.SawWaveCheckBox = uicheckbox(app.SynthesizerPanel);
            app.SawWaveCheckBox.Text = 'Saw Wave';
            app.PitchDropDownLabel = uilabel(app.SynthesizerPanel);
            app.PitchDropDownLabel.Text = 'Pitch';
            app.PitchDropDown = uidropdown(app.SynthesizerPanel);
            app.LunchPadTab = uitab(app.TabGroup);
            app.LunchPadTab.Title = 'LunchPad';
            app.MelodyButton_3 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_3.Text = 'Melody';
            app.MelodyButton_8 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_8.Text = 'Melody';
            app.MelodyButton_4 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_4.Text = 'Melody';
            app.BassButton_4 = uibutton(app.LunchPadTab, 'push');
            app.BassButton_4.Text = 'Bass';
            app.DrumButton_4 = uibutton(app.LunchPadTab, 'push');
            app.DrumButton_4.Text = 'Drum';
            app.MelodyButton_7 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_7.Text = 'Melody';
            app.BassButton_3 = uibutton(app.LunchPadTab, 'push');
            app.BassButton_3.Text = 'Bass';
            app.DrumButton_3 = uibutton(app.LunchPadTab, 'push');
            app.DrumButton_3.Text = 'Drum';
            app.MelodyButton_6 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_6.Text = 'Melody';
            app.MelodyButton_2 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_2.Text = 'Melody';
            app.BassButton_2 = uibutton(app.LunchPadTab, 'push');
            app.BassButton_2.Text = 'Bass';
            app.DrumButton_2 = uibutton(app.LunchPadTab, 'push');
            app.DrumButton_2.Text = 'Drum';
            app.MelodyButton_5 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_5.Text = 'Melody';
            app.MelodyButton = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton.Text = 'Melody';
            app.BassButton = uibutton(app.LunchPadTab, 'push');
            app.BassButton.Text = 'Bass';
            app.DrumButton = uibutton(app.LunchPadTab, 'push');
            app.DrumButton.Text = 'Drum';
            app.SoundNoDropDown_4 = uidropdown(app.LunchPadTab);
            app.SoundNoDropDown_4.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SoundNoDropDown_4.Value = 'Sound 1';
            app.SoundNoDropDown_4Label = uilabel(app.LunchPadTab);
            app.SoundNoDropDown_4Label.Text = 'Sound No.';
            app.SaveButton_3 = uibutton(app.LunchPadTab, 'push');
            app.SaveButton_3.Text = 'Save';
            app.SavingPlaceDropDown_3 = uidropdown(app.LunchPadTab);
            app.SavingPlaceDropDown_3.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SavingPlaceDropDown_3.Value = 'Sound 1';
            app.SavingPlaceDropDown_3Label = uilabel(app.LunchPadTab);
            app.SavingPlaceDropDown_3Label.Text = 'Saving Place';
            app.PlayButton_3 = uibutton(app.LunchPadTab, 'push');
            app.PlayButton_3.Text = 'Play';
            app.StartPointSliderLabel = uilabel(app.LunchPadTab);
            app.StartPointSliderLabel.Text = 'Start Point';
            app.StartPointSlider = uislider(app.LunchPadTab);
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            app.SoundPanel = uipanel(app.UIFigure);
            app.SoundPanel.Title = 'Sound';
            app.Sound1Button = uibutton(app.SoundPanel, 'push');
            app.Sound1Button.ButtonPushedFcn = createCallbackFcn(app, @Sound1ButtonPushed, true);
            app.Sound1Button.Text = 'Sound1';
            app.Sound2Button = uibutton(app.SoundPanel, 'push');
            app.Sound2Button.ButtonPushedFcn = createCallbackFcn(app, @Sound2ButtonPushed, true);
            app.Sound2Button.Text = 'Sound2';
            app.Sound3Button = uibutton(app.SoundPanel, 'push');
            app.Sound3Button.ButtonPushedFcn = createCallbackFcn(app, @Sound3ButtonPushed, true);
            app.Sound3Button.Text = 'Sound3';
            app.Sound4Button = uibutton(app.SoundPanel, 'push');
            app.Sound4Button.ButtonPushedFcn = createCallbackFcn(app, @Sound4ButtonPushed, true);
            app.Sound4Button.Text = 'Sound4';
            app.Sound5Button = uibutton(app.SoundPanel, 'push');
            app.Sound5Button.ButtonPushedFcn = createCallbackFcn(app, @Sound5ButtonPushed, true);
            app.Sound5Button.Text = 'Sound5';
            app.Sound6Button = uibutton(app.SoundPanel, 'push');
            app.Sound6Button.ButtonPushedFcn = createCallbackFcn(app, @Sound6ButtonPushed, true);
            app.Sound6Button.Text = 'Sound6';
            app.Sound7Button = uibutton(app.SoundPanel, 'push');
            app.Sound7Button.ButtonPushedFcn = createCallbackFcn(app, @Sound7ButtonPushed, true);
            app.Sound7Button.Text = 'Sound7';
            app.Sound8Button = uibutton(app.SoundPanel, 'push');
            app.Sound8Button.ButtonPushedFcn = createCallbackFcn(app, @Sound8ButtonPushed, true);
            app.Sound8Button.Text = 'Sound8';
            app.Sound9Button = uibutton(app.SoundPanel, 'push');
            app.Sound9Button.ButtonPushedFcn = createCallbackFcn(app, @Sound9ButtonPushed, true);
            app.Sound9Button.Text = 'Sound9';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TeamProject2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end