classdef TeamProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SoundPanel                    matlab.ui.container.Panel
        EditField_9                   matlab.ui.control.EditField
        EditField_8                   matlab.ui.control.EditField
        EditField_7                   matlab.ui.control.EditField
        EditField_6                   matlab.ui.control.EditField
        EditField_5                   matlab.ui.control.EditField
        EditField_4                   matlab.ui.control.EditField
        EditField_3                   matlab.ui.control.EditField
        EditField_2                   matlab.ui.control.EditField
        EditField                     matlab.ui.control.EditField
        Sound9Button                  matlab.ui.control.Button
        Sound8Button                  matlab.ui.control.Button
        Sound7Button                  matlab.ui.control.Button
        Sound6Button                  matlab.ui.control.Button
        Sound5Button                  matlab.ui.control.Button
        Sound4Button                  matlab.ui.control.Button
        Sound3Button                  matlab.ui.control.Button
        Sound2Button                  matlab.ui.control.Button
        Sound1Button                  matlab.ui.control.Button
        MessageTextArea               matlab.ui.control.TextArea
        MessageTextAreaLabel          matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        BasicModeTab                  matlab.ui.container.Tab
        SynthesizerPanel              matlab.ui.container.Panel
        PitchDropDown                 matlab.ui.control.DropDown
        PitchDropDownLabel            matlab.ui.control.Label
        SawWaveCheckBox               matlab.ui.control.CheckBox
        TriangleWaveCheckBox          matlab.ui.control.CheckBox
        PulseWaveCheckBox             matlab.ui.control.CheckBox
        SineWaveCheckBox              matlab.ui.control.CheckBox
        PlayButton_2                  matlab.ui.control.StateButton
        SavingPlaceDropDownLabel      matlab.ui.control.Label
        SavingPlaceDropDown           matlab.ui.control.DropDown
        SoundNoDropDown_3Label        matlab.ui.control.Label
        SoundNoDropDown_3             matlab.ui.control.DropDown
        SaveButton_2                  matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        ChoppingPanel                 matlab.ui.container.Panel
        SavingPlaceDropDown_2Label    matlab.ui.control.Label
        SavingPlaceDropDown_2         matlab.ui.control.DropDown
        PlayButton                    matlab.ui.control.StateButton
        SaveButton                    matlab.ui.control.Button
        SoundNoDropDown_2             matlab.ui.control.DropDown
        SoundNoDropDown_2Label        matlab.ui.control.Label
        SoundSlider                   matlab.ui.control.RangeSlider
        SoundSliderLabel              matlab.ui.control.Label
        EqualizerPanel                matlab.ui.container.Panel
        PlayCheckBox_2                matlab.ui.control.CheckBox
        HzSlider_7                    matlab.ui.control.Slider
        HzLabel_3                     matlab.ui.control.Label
        KHzSlider_2                   matlab.ui.control.Slider
        KHzSlider_2Label              matlab.ui.control.Label
        KHzSlider                     matlab.ui.control.Slider
        KHzSliderLabel                matlab.ui.control.Label
        HzSlider_4                    matlab.ui.control.Slider
        HzLabel_4                     matlab.ui.control.Label
        HzSlider_3                    matlab.ui.control.Slider
        HzLabel_2                     matlab.ui.control.Label
        HzSlider_2                    matlab.ui.control.Slider
        HzLabel                       matlab.ui.control.Label
        EffectandSampleModificationPanel  matlab.ui.container.Panel
        EchoKnob                      matlab.ui.control.Knob
        EchoKnobLabel                 matlab.ui.control.Label
        PlaybackSpeedSlider           matlab.ui.control.Slider
        PlaybackSpeedSliderLabel      matlab.ui.control.Label
        PlaybackDirectionSwitch       matlab.ui.control.Switch
        PlaybackDirectionSwitchLabel  matlab.ui.control.Label
        PlaySwitch                    matlab.ui.control.ToggleSwitch
        PlaySwitchLabel               matlab.ui.control.Label
        VolumeKnob                    matlab.ui.control.Knob
        VolumeKnobLabel               matlab.ui.control.Label
        SoundNoDropDown               matlab.ui.control.DropDown
        SoundNoDropDownLabel          matlab.ui.control.Label
        LunchPadTab                   matlab.ui.container.Tab
        StartPointSlider              matlab.ui.control.Slider
        StartPointSliderLabel         matlab.ui.control.Label
        PlayButton_3                  matlab.ui.control.Button
        SavingPlaceDropDown_3Label    matlab.ui.control.Label
        SavingPlaceDropDown_3         matlab.ui.control.DropDown
        SaveButton_3                  matlab.ui.control.Button
        SoundNoDropDown_4Label        matlab.ui.control.Label
        SoundNoDropDown_4             matlab.ui.control.DropDown
        DrumButton                    matlab.ui.control.Button
        BassButton                    matlab.ui.control.Button
        MelodyButton                  matlab.ui.control.Button
        MelodyButton_5                matlab.ui.control.Button
        DrumButton_2                  matlab.ui.control.Button
        BassButton_2                  matlab.ui.control.Button
        MelodyButton_2                matlab.ui.control.Button
        MelodyButton_6                matlab.ui.control.Button
        DrumButton_3                  matlab.ui.control.Button
        BassButton_3                  matlab.ui.control.Button
        MelodyButton_7                matlab.ui.control.Button
        DrumButton_4                  matlab.ui.control.Button
        BassButton_4                  matlab.ui.control.Button
        MelodyButton_4                matlab.ui.control.Button
        MelodyButton_8                matlab.ui.control.Button
        MelodyButton_3                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        audio = struct('yValue', {}, 'frequency', {}, 'filename', {});
        sbutton = struct("s1button", false, "s2button", false, "s3button", false, ...
        "s4button", false, "s5button", false, "s6button", false, "s7button", false,...
        "s8button", false, "s9button", false);
        AudioPlayer audioplayer;
    end
    
    methods (Access = private)
        
        function [yvalue, Fs , fileName] = soundInput(app)
            [filename,filepath] =uigetfile({'*.wav; *.mp3'}, 'Select File to Open');
            fullname = [filepath, filename];
            [firstValue, secondValue] = audioread(fullname);
            yvalue = firstValue;
            Fs = secondValue; 
            fileName = filename;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Sound1Button
        function Sound1ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput(); 
            app.sbutton(1).s1button = true;
            app.audio(1).yValue = y; 
            app.audio(1).frequency = Fs;
            app.audio(1).filename = filename;
            app.EditField.Value = filename;
            disp("audio_1 setting finish");
            app.Sound1Button.FontColor = [0, 0.8, 0];
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
        end

        % Button pushed function: Sound2Button
        function Sound2ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput();
            app.sbutton(2).s2button = true;
               
            app.audio(2).yValue = y; 
            app.audio(2).frequency = Fs; 
            app.audio(2).filename = filename;
            app.EditField_2.Value = filename;
            disp("audio_2 setting finish");
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
            app.Sound2Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound3Button
        function Sound3ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput(); 
            app.audio(3).yValue = y; 
            app.audio(3).frequency = Fs; 
            app.audio(3).filename = filename;
            app.EditField_3.Value = filename;
            disp("audio_3 setting finish");
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
            app.Sound3Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound4Button
        function Sound4ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput(); 
            app.audio(4).yValue = y; 
            app.audio(4).frequency = Fs; 
            app.audio(4).filename = filename;
            app.EditField_4.Value = filename;
            disp("audio_4 setting finish");
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
            app.Sound4Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound5Button
        function Sound5ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput(); 
            app.audio(5).yValue = y; 
            app.audio(5).frequency = Fs; 
            app.audio(5).filename = filename;
            app.EditField_5.Value = filename;
            disp("audio_5 setting finish");
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
            app.Sound5Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound6Button
        function Sound6ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput(); 
            app.audio(6).yValue = y; 
            app.audio(6).frequency = Fs; 
            app.audio(6).filename = filename;
            app.EditField_6.Value = filename;
            disp("audio_6 setting finish");
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
            app.Sound6Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound7Button
        function Sound7ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput(); 
            app.audio(7).yValue = y; 
            app.audio(7).frequency = Fs; 
            app.audio(7).filename = filename;
            app.EditField_7.Value = filename;
            disp("audio_7 setting finish");
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
            app.Sound7Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound8Button
        function Sound8ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput(); 
            app.audio(8).yValue = y; 
            app.audio(8).frequency = Fs; 
            app.audio(8).filename = filename;
            app.EditField_8.Value = filename;
            disp("audio_8 setting finish");
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
            app.Sound8Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound9Button
        function Sound9ButtonPushed(app, event)
            [y, Fs, filename]= app.soundInput(); 
            app.audio(9).yValue = y; 
            app.audio(9).frequency = Fs; 
            app.audio(9).filename = filename;
            app.EditField_9.Value = filename;
            disp("audio_9 setting finish");
            t = seconds(0:1/Fs:(size(y,1)-1)/Fs);
            plot(app.UIAxes,t,y)
            app.Sound9Button.FontColor = [0, 0.8, 0];
        end

        % Value changed function: PlaySwitch
        function PlaySwitchValueChanged(app, event)
            value = app.PlaySwitch.Value;
            volume = app.VolumeKnob.Value;
            playSpeed = app.PlaybackSpeedSlider.Value;
            if app.sbutton(1).s1button == 1
                if app.PlaybackDirectionSwitch.Value == "Backward"
                    backwardY = app.audio(1).yValue(end:-1:1, :);
                    app.AudioPlayer = audioplayer(backwardY*volume, app.audio(1).frequency*playSpeed);
                    play(app.AudioPlayer);
                else
                    app.AudioPlayer = audioplayer(app.audio(1).yValue*volume,app.audio(1).frequency*playSpeed);
                    play(app.AudioPlayer);
                end
            elseif app.sbutton(2) == 1
                if app.PlaybackDirectionSwitch.Value == "Backward"
                    backwardY = app.audio(2)*volume.yValue(end:-1:1);
                    app.AudioPlayer = audioplayer(backwardY*volume,app.audio(2).frequency);
                    play(app.AudioPlayer);
                else
                    app.AudioPlayer = audioplayer(app.audio(2).yValue*volume,app.audio(2).frequency);
                    play(app.AudioPlayer);
                end
            elseif app.sbutton(3) == 1
                if app.PlaybackDirectionSwitch.Value == "Backward"
                    backwardY = app.audio(3)*volume.yValue(end:-1:1);
                    app.AudioPlayer = audioplayer(backwardY*volume,app.audio(3).frequency);
                    play(app.AudioPlayer);
                   
                else
                    app.AudioPlayer = audioplayer(app.audio(3).yValue*volume,app.audio(3).frequency);
                    play(app.AudioPlayer);
                end
            elseif app.sbutton(4) == 1
                if app.PlaybackDirectionSwitch.Value == "Backward"
                    backwardY = app.audio(4)*volume.yValue(end:-1:1);
                    app.AudioPlayer = audioplayer(backwardY*volume,app.audio(4).frequency);
                    play(app.AudioPlayer);
                else
                    app.AudioPlayer = audioplayer(app.audio(4).yValue*volume,app.audio(4).frequency);
                    play(app.AudioPlayer);
                end
            elseif app.sbutton(5) == 1
                if app.PlaybackDirectionSwitch.Value == "Backward"
                    backwardY = app.audio(5)*volume.yValue(end:-1:1);
                    app.AudioPlayer = audioplayer(backwardY*volume,app.audio(5).frequency);
                    play(app.AudioPlayer);
                else
                    app.AudioPlayer = audioplayer(app.audio(5).yValue*volume,app.audio(5).frequency);
                    play(app.AudioPlayer);
                end
            elseif app.sbutton(6) == 1
                if app.PlaybackDirectionSwitch.Value == "Backward"
                    backwardY = app.audio(6)*volume.yValue(end:-1:1);
                    app.AudioPlayer = audioplayer(backwardY*volume,app.audio(6).frequency);
                    play(app.AudioPlayer);
                else
                    app.AudioPlayer = audioplayer(app.audio(6).yValue*volume,app.audio(6).frequency);
                    play(app.AudioPlayer);
                end
            elseif app.sbutton(7) == 1
                if app.PlaybackDirectionSwitch.Value == "Backward"
                    backwardY = app.audio(8)*volume.yValue(end:-1:1);
                    app.AudioPlayer = audioplayer(backwardY*volume,app.audio(8).frequency);
                    play(app.AudioPlayer);
                else
                    app.AudioPlayer = audioplayer(app.audio(8).yValue*volume,app.audio(8).frequency);
                    play(app.AudioPlayer);
                end
            elseif app.sbutton(8) == 1
                if app.PlaybackDirectionSwitch.Value == "Backward"
                    backwardY = app.audio(8)*volume.yValue(end:-1:1);
                    app.AudioPlayer = audioplayer(backwardY*volume,app.audio(8).frequency);
                    play(app.AudioPlayer);
                else
                    app.AudioPlayer = audioplayer(app.audio(8).yValue*volume,app.audio(8).frequency);
                    play(app.AudioPlayer);
                end
            elseif app.s9button == 1 
                if app.PlaybackDirectionSwitch.Value == "Backward"
                    backwardY = app.audio(9)*volume.yValue(end:-1:1);
                    app.AudioPlayer = audioplayer(backwardY*volume,app.audio(9).frequency);
                    play(app.AudioPlayer);
                else
                    app.AudioPlayer = audioplayer(app.audio(9).yValue*volume,app.audio(9).frequency);
                    play(app.AudioPlayer);
                end
            end
            if value == "Off"
                pause(app.AudioPlayer);
            end

        end

        % Value changed function: SoundNoDropDown
        function SoundNoDropDownValueChanged(app, event)
            value = app.SoundNoDropDown.Value;
            switch value
                case "Sound 1"
                    app.s1button = 1;
                case "Sound 2"
                    app.s2button = 1;
                case "Sound 3"
                    app.s3button = 1;
                case "Sound 4"
                    app.s4button = 1;
                case "Sound 5"
                    app.s5button = 1;
                case "Sound 6"
                    app.s6button = 1;
                case "Sound 7"
                    app.s7button = 1;
                case "Sound 8" 
                    app.s8button = 1;
                case "Sound 9 "
                    app.s9button = 1;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sound Wave')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicModeTab = uitab(app.TabGroup);
            app.BasicModeTab.Title = 'Basic Mode';
            app.EffectandSampleModificationPanel = uipanel(app.BasicModeTab);
            app.EffectandSampleModificationPanel.Title = 'Effect and Sample Modification ';
            app.SoundNoDropDownLabel = uilabel(app.EffectandSampleModificationPanel);
            app.SoundNoDropDownLabel.Text = 'Sound No.';
            app.SoundNoDropDown = uidropdown(app.EffectandSampleModificationPanel);
            app.SoundNoDropDown.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SoundNoDropDown.ValueChangedFcn = createCallbackFcn(app, @SoundNoDropDownValueChanged, true);
            app.SoundNoDropDown.Value = 'Sound 1';
            app.VolumeKnobLabel = uilabel(app.EffectandSampleModificationPanel);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.EffectandSampleModificationPanel, 'continuous');
            app.VolumeKnob.Value = 50;
            app.PlaySwitchLabel = uilabel(app.EffectandSampleModificationPanel);
            app.PlaySwitchLabel.Text = 'Play';
            app.PlaySwitch = uiswitch(app.EffectandSampleModificationPanel, 'toggle');
            app.PlaySwitch.ValueChangedFcn = createCallbackFcn(app, @PlaySwitchValueChanged, true);
            app.PlaybackDirectionSwitchLabel = uilabel(app.EffectandSampleModificationPanel);
            app.PlaybackDirectionSwitchLabel.Text = 'Playback Direction';
            app.PlaybackDirectionSwitch = uiswitch(app.EffectandSampleModificationPanel, 'slider');
            app.PlaybackDirectionSwitch.Items = {'Forward', 'Backward'};
            app.PlaybackDirectionSwitch.Value = 'Forward';
            app.PlaybackSpeedSliderLabel = uilabel(app.EffectandSampleModificationPanel);
            app.PlaybackSpeedSliderLabel.Text = 'Playback Speed';
            app.PlaybackSpeedSlider = uislider(app.EffectandSampleModificationPanel);
            app.PlaybackSpeedSlider.Limits = [0.1 2];
            app.PlaybackSpeedSlider.MajorTicks = [1 2 3 4 5];
            app.PlaybackSpeedSlider.Value = 1;
            app.EchoKnobLabel = uilabel(app.EffectandSampleModificationPanel);
            app.EchoKnobLabel.Text = 'Echo';
            app.EchoKnob = uiknob(app.EffectandSampleModificationPanel, 'continuous');
            app.EqualizerPanel = uipanel(app.BasicModeTab);
            app.EqualizerPanel.Title = 'Equalizer';
            app.HzLabel = uilabel(app.EqualizerPanel);
            app.HzLabel.Text = '170Hz';
            app.HzSlider_2 = uislider(app.EqualizerPanel);
            app.HzSlider_2.Limits = [-3 3];
            app.HzLabel_2 = uilabel(app.EqualizerPanel);
            app.HzLabel_2.Text = '310Hz';
            app.HzSlider_3 = uislider(app.EqualizerPanel);
            app.HzSlider_3.Limits = [-3 3];
            app.HzLabel_4 = uilabel(app.EqualizerPanel);
            app.HzLabel_4.Text = '600Hz';
            app.HzSlider_4 = uislider(app.EqualizerPanel);
            app.HzSlider_4.Limits = [-3 3];
            app.KHzSliderLabel = uilabel(app.EqualizerPanel);
            app.KHzSliderLabel.Text = '1KHz';
            app.KHzSlider = uislider(app.EqualizerPanel);
            app.KHzSlider.Limits = [-3 3];
            app.KHzSlider_2Label = uilabel(app.EqualizerPanel);
            app.KHzSlider_2Label.Text = '3KHz';
            app.KHzSlider_2 = uislider(app.EqualizerPanel);
            app.KHzSlider_2.Limits = [-3 3];
            app.HzLabel_3 = uilabel(app.EqualizerPanel);
            app.HzLabel_3.Text = '60Hz';
            app.HzSlider_7 = uislider(app.EqualizerPanel);
            app.HzSlider_7.Limits = [-3 3];
            app.PlayCheckBox_2 = uicheckbox(app.EqualizerPanel);
            app.PlayCheckBox_2.Text = 'Play';
            app.ChoppingPanel = uipanel(app.BasicModeTab);
            app.ChoppingPanel.Title = 'Chopping';
            app.SoundSliderLabel = uilabel(app.ChoppingPanel);
            app.SoundSliderLabel.Text = 'Sound';
            app.SoundSlider = uislider(app.ChoppingPanel, 'range');
            app.SoundNoDropDown_2Label = uilabel(app.ChoppingPanel);
            app.SoundNoDropDown_2Label.Text = 'Sound No.';
            app.SoundNoDropDown_2 = uidropdown(app.ChoppingPanel);
            app.SoundNoDropDown_2.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SoundNoDropDown_2.Value = 'Sound 1';
            app.SaveButton = uibutton(app.ChoppingPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.PlayButton = uibutton(app.ChoppingPanel, 'state');
            app.PlayButton.Text = 'Play';
            app.SavingPlaceDropDown_2 = uidropdown(app.ChoppingPanel);
            app.SavingPlaceDropDown_2.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SavingPlaceDropDown_2.Value = 'Sound 1';
            app.SavingPlaceDropDown_2Label = uilabel(app.ChoppingPanel);
            app.SavingPlaceDropDown_2Label.Text = 'Saving Place';
            app.SynthesizerPanel = uipanel(app.BasicModeTab);
            app.SynthesizerPanel.Title = 'Synthesizer';
            app.UIAxes2 = uiaxes(app.SynthesizerPanel);
            title(app.UIAxes2, 'Synthesized Wave')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.SaveButton_2 = uibutton(app.SynthesizerPanel, 'push');
            app.SaveButton_2.Text = 'Save';
            app.SoundNoDropDown_3 = uidropdown(app.SynthesizerPanel);
            app.SoundNoDropDown_3.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SoundNoDropDown_3.Value = 'Sound 1';
            app.SoundNoDropDown_3Label = uilabel(app.SynthesizerPanel);
            app.SoundNoDropDown_3Label.Text = 'Sound No.';
            app.SavingPlaceDropDown = uidropdown(app.SynthesizerPanel);
            app.SavingPlaceDropDown.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SavingPlaceDropDown.Value = 'Sound 1';
            app.SavingPlaceDropDownLabel = uilabel(app.SynthesizerPanel);
            app.SavingPlaceDropDownLabel.Text = 'Saving Place';
            app.PlayButton_2 = uibutton(app.SynthesizerPanel, 'state');
            app.PlayButton_2.Text = 'Play';
            app.SineWaveCheckBox = uicheckbox(app.SynthesizerPanel);
            app.SineWaveCheckBox.Text = 'Sine Wave';
            app.PulseWaveCheckBox = uicheckbox(app.SynthesizerPanel);
            app.PulseWaveCheckBox.Text = 'Pulse Wave';
            app.TriangleWaveCheckBox = uicheckbox(app.SynthesizerPanel);
            app.TriangleWaveCheckBox.Text = 'Triangle Wave';
            app.SawWaveCheckBox = uicheckbox(app.SynthesizerPanel);
            app.SawWaveCheckBox.Text = 'Saw Wave';
            app.PitchDropDownLabel = uilabel(app.SynthesizerPanel);
            app.PitchDropDownLabel.Text = 'Pitch';
            app.PitchDropDown = uidropdown(app.SynthesizerPanel);
            app.LunchPadTab = uitab(app.TabGroup);
            app.LunchPadTab.Title = 'LunchPad';
            app.MelodyButton_3 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_3.Text = 'Melody';
            app.MelodyButton_8 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_8.Text = 'Melody';
            app.MelodyButton_4 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_4.Text = 'Melody';
            app.BassButton_4 = uibutton(app.LunchPadTab, 'push');
            app.BassButton_4.Text = 'Bass';
            app.DrumButton_4 = uibutton(app.LunchPadTab, 'push');
            app.DrumButton_4.Text = 'Drum';
            app.MelodyButton_7 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_7.Text = 'Melody';
            app.BassButton_3 = uibutton(app.LunchPadTab, 'push');
            app.BassButton_3.Text = 'Bass';
            app.DrumButton_3 = uibutton(app.LunchPadTab, 'push');
            app.DrumButton_3.Text = 'Drum';
            app.MelodyButton_6 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_6.Text = 'Melody';
            app.MelodyButton_2 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_2.Text = 'Melody';
            app.BassButton_2 = uibutton(app.LunchPadTab, 'push');
            app.BassButton_2.Text = 'Bass';
            app.DrumButton_2 = uibutton(app.LunchPadTab, 'push');
            app.DrumButton_2.Text = 'Drum';
            app.MelodyButton_5 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_5.Text = 'Melody';
            app.MelodyButton = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton.Text = 'Melody';
            app.BassButton = uibutton(app.LunchPadTab, 'push');
            app.BassButton.Text = 'Bass';
            app.DrumButton = uibutton(app.LunchPadTab, 'push');
            app.DrumButton.Text = 'Drum';
            app.SoundNoDropDown_4 = uidropdown(app.LunchPadTab);
            app.SoundNoDropDown_4.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SoundNoDropDown_4.Value = 'Sound 1';
            app.SoundNoDropDown_4Label = uilabel(app.LunchPadTab);
            app.SoundNoDropDown_4Label.Text = 'Sound No.';
            app.SaveButton_3 = uibutton(app.LunchPadTab, 'push');
            app.SaveButton_3.Text = 'Save';
            app.SavingPlaceDropDown_3 = uidropdown(app.LunchPadTab);
            app.SavingPlaceDropDown_3.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SavingPlaceDropDown_3.Value = 'Sound 1';
            app.SavingPlaceDropDown_3Label = uilabel(app.LunchPadTab);
            app.SavingPlaceDropDown_3Label.Text = 'Saving Place';
            app.PlayButton_3 = uibutton(app.LunchPadTab, 'push');
            app.PlayButton_3.Text = 'Play';
            app.StartPointSliderLabel = uilabel(app.LunchPadTab);
            app.StartPointSliderLabel.Text = 'Start Point';
            app.StartPointSlider = uislider(app.LunchPadTab);
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            app.SoundPanel = uipanel(app.UIFigure);
            app.SoundPanel.Title = 'Sound';
            app.Sound1Button = uibutton(app.SoundPanel, 'push');
            app.Sound1Button.ButtonPushedFcn = createCallbackFcn(app, @Sound1ButtonPushed, true);
            app.Sound1Button.Text = 'Sound1';
            app.Sound2Button = uibutton(app.SoundPanel, 'push');
            app.Sound2Button.ButtonPushedFcn = createCallbackFcn(app, @Sound2ButtonPushed, true);
            app.Sound2Button.Text = 'Sound2';
            app.Sound3Button = uibutton(app.SoundPanel, 'push');
            app.Sound3Button.ButtonPushedFcn = createCallbackFcn(app, @Sound3ButtonPushed, true);
            app.Sound3Button.Text = 'Sound3';
            app.Sound4Button = uibutton(app.SoundPanel, 'push');
            app.Sound4Button.ButtonPushedFcn = createCallbackFcn(app, @Sound4ButtonPushed, true);
            app.Sound4Button.Text = 'Sound4';
            app.Sound5Button = uibutton(app.SoundPanel, 'push');
            app.Sound5Button.ButtonPushedFcn = createCallbackFcn(app, @Sound5ButtonPushed, true);
            app.Sound5Button.Text = 'Sound5';
            app.Sound6Button = uibutton(app.SoundPanel, 'push');
            app.Sound6Button.ButtonPushedFcn = createCallbackFcn(app, @Sound6ButtonPushed, true);
            app.Sound6Button.Text = 'Sound6';
            app.Sound7Button = uibutton(app.SoundPanel, 'push');
            app.Sound7Button.ButtonPushedFcn = createCallbackFcn(app, @Sound7ButtonPushed, true);
            app.Sound7Button.Text = 'Sound7';
            app.Sound8Button = uibutton(app.SoundPanel, 'push');
            app.Sound8Button.ButtonPushedFcn = createCallbackFcn(app, @Sound8ButtonPushed, true);
            app.Sound8Button.Text = 'Sound8';
            app.Sound9Button = uibutton(app.SoundPanel, 'push');
            app.Sound9Button.ButtonPushedFcn = createCallbackFcn(app, @Sound9ButtonPushed, true);
            app.Sound9Button.Text = 'Sound9';
            app.EditField = uieditfield(app.SoundPanel, 'text');
            app.EditField_2 = uieditfield(app.SoundPanel, 'text');
            app.EditField_3 = uieditfield(app.SoundPanel, 'text');
            app.EditField_4 = uieditfield(app.SoundPanel, 'text');
            app.EditField_5 = uieditfield(app.SoundPanel, 'text');
            app.EditField_6 = uieditfield(app.SoundPanel, 'text');
            app.EditField_7 = uieditfield(app.SoundPanel, 'text');
            app.EditField_8 = uieditfield(app.SoundPanel, 'text');
            app.EditField_9 = uieditfield(app.SoundPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TeamProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end